classdef Register < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Reg3DV200WukongUIFigure    matlab.ui.Figure
        Menu_File                  matlab.ui.container.Menu
        Menu_LoadFile              matlab.ui.container.Menu
        Menu_LoadMem               matlab.ui.container.Menu
        Menu_SaveImageAs           matlab.ui.container.Menu
        Menu_SaveGeoTformAs        matlab.ui.container.Menu
        Menu_SaveTimeAs            matlab.ui.container.Menu
        Menu_SaveMovieAs           matlab.ui.container.Menu
        Menu_Quit                  matlab.ui.container.Menu
        Menu_Image                 matlab.ui.container.Menu
        Menu_LightContrast         matlab.ui.container.Menu
        Menu_ZProjection           matlab.ui.container.Menu
        Menu_MaxZProjection        matlab.ui.container.Menu
        Menu_MinZProjection        matlab.ui.container.Menu
        Menu_MedianZProjection     matlab.ui.container.Menu
        Menu_MeanZProjection       matlab.ui.container.Menu
        Menu_ChannelAlignment      matlab.ui.container.Menu
        Menu_RemoveBackGround      matlab.ui.container.Menu
        Menu_RemoveBackgroundAuto  matlab.ui.container.Menu
        Menu_RemoveBackgroundFix   matlab.ui.container.Menu
        Menu_MaskManager           matlab.ui.container.Menu
        Menu_GenerateMask          matlab.ui.container.Menu
        Menu_LoadMask              matlab.ui.container.Menu
        Menu_Comments              matlab.ui.container.Menu
        Menu_TimeStamp             matlab.ui.container.Menu
        Menu_ScaleBar              matlab.ui.container.Menu
        Menu_MetaMarker            matlab.ui.container.Menu
        Menu_Crop3D                matlab.ui.container.Menu
        Menu_CropXY                matlab.ui.container.Menu
        Menu_CropZ                 matlab.ui.container.Menu
        Menu_CropT                 matlab.ui.container.Menu
        Menu_Options               matlab.ui.container.Menu
        Menu_SaveSetting           matlab.ui.container.Menu
        Menu_LoadSetting           matlab.ui.container.Menu
        Menu_Animation             matlab.ui.container.Menu
        Menu_1F                    matlab.ui.container.Menu
        Menu_5F                    matlab.ui.container.Menu
        Menu_25F                   matlab.ui.container.Menu
        Menu_KeyMode               matlab.ui.container.Menu
        Menu_Direct                matlab.ui.container.Menu
        Menu_Loop                  matlab.ui.container.Menu
        Menu_Register              matlab.ui.container.Menu
        Menu_RegOptions            matlab.ui.container.Menu
        Menu_RegRange              matlab.ui.container.Menu
        Menu_RegEvaluate           matlab.ui.container.Menu
        Menu_RegHistory            matlab.ui.container.Menu
        Menu_RunModal              matlab.ui.container.Menu
        Menu_RunModalDistributed   matlab.ui.container.Menu
        Menu_RunModalExclusive     matlab.ui.container.Menu
        Menu_TaskViewer            matlab.ui.container.Menu
        Menu_Help                  matlab.ui.container.Menu
        Menu_About                 matlab.ui.container.Menu
        Menu_Documents             matlab.ui.container.Menu
        Menu_Language              matlab.ui.container.Menu
        Menu_Chinese               matlab.ui.container.Menu
        Menu_English               matlab.ui.container.Menu
        Menu_Russian               matlab.ui.container.Menu
        Menu_French                matlab.ui.container.Menu
        Menu_Spanish               matlab.ui.container.Menu
        Menu_Update                matlab.ui.container.Menu
        Menu_SubmitBug             matlab.ui.container.Menu
        Panel_Control              matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout4                matlab.ui.container.GridLayout
        Button_Run                 matlab.ui.control.StateButton
        DropDown_Source            matlab.ui.control.DropDown
        Label_19                   matlab.ui.control.Label
        GridLayout3                matlab.ui.container.GridLayout
        Label_20                   matlab.ui.control.Label
        TextArea_Log               matlab.ui.control.TextArea
        Switch_Log                 matlab.ui.control.RockerSwitch
        Panel_SliceViewer          matlab.ui.container.Panel
        GridLayout_Viewer          matlab.ui.container.GridLayout
        Spinner_Slice              matlab.ui.control.Spinner
        GridLayout                 matlab.ui.container.GridLayout
        Spinner_Time               matlab.ui.control.Spinner
        Slider_Slice               matlab.ui.control.Slider
        Label_2                    matlab.ui.control.Label
        Slider_Time                matlab.ui.control.Slider
        Label                      matlab.ui.control.Label
        UIAxes_Slice               matlab.ui.control.UIAxes
        ContextMenu_Player         matlab.ui.container.ContextMenu
        Menu_Horizontal            matlab.ui.container.Menu
        Menu_Vertical              matlab.ui.container.Menu
        Menu_ROI                   matlab.ui.container.Menu
        Menu_RectangleROI          matlab.ui.container.Menu
        Menu_CircleROI             matlab.ui.container.Menu
        Menu_PolygonROI            matlab.ui.container.Menu
        Menu_FreehandROI           matlab.ui.container.Menu
        Menu_Channel               matlab.ui.container.Menu
        ContextMenu_Controller     matlab.ui.container.ContextMenu
        Menu_ExportLog             matlab.ui.container.Menu
        Menu_ClearLog              matlab.ui.container.Menu
    end

    properties (Access = public)
        % dynamic components
        Menu_MergeSource            matlab.ui.container.Menu
        Menu_MergeNone              matlab.ui.container.Menu
        Menu_MergeRB                matlab.ui.container.Menu
        Menu_MergeGR                matlab.ui.container.Menu
        Menu_Red                    matlab.ui.container.Menu
        Menu_Green                  matlab.ui.container.Menu

        % export movie setting
        mov_export_setting
    end

    properties (GetAccess = public, Constant)
        REG_COMPELATE = 2
        REG_RUNNING = 1
        REG_READY = 0
        REGMODE_NOT_READY = -1
        SOURCE_NOT_READY = -2

        VER = "2.0.0"
        BUG_REPORT_HOST = "192.168.21.73:2222"
        BUG_REPORT_FOLDER = "/data/LiWeiHan/Reg3D bugs collection/"
        EMAIL_TO = "liweihan20@mails.ucas.ac.cn"
    end

    properties (Access = private)
        % data source
        mov_raw            % the raw movie info, regmov object
        mov_aligned        % the aligned movie info, regmov object
        reg_opts           % the registration options
        memmap_flag        % the memory mapping flag, for shared(true) or extensive(false) modal
        reg_frames         % the registration frames series, indices array
        roi_cur            % the current ROI, struct with xy, f(for frame)
        zprojimg           % the z projection image struct, with source and aligned
        tprojimg           % the t projection image struct, with source and aligned

        % viewing/operation position indicator/information
        frame_idx_cur      % the current frame index
        slice_idx_cur      % the current slice index
        channel_idx_cur    % the current channel index
        sliceimg_cur       % the current slice image front-end, 2D/3D matrix for viewing
        channel_order      % the channels order
        view_opts          % struct, field {b, c, ga}
        viewer_src         % string, could be "raw" or "aligned", where "raw"
                           % for the source movie, and aligned for aligned movie
        scroll_ylim        % the y position limitation of scroll image

        % viewing/playing mode
        zproj_mode         % the flag for current viewing mode, "none", "max","min","mean","median"
        merge_mode         % string, the mode for raw/aligned merge viewing,
                           % could be "none", "red-cyan", "green-magenta"
        key_mode           % the keyboard fast control mode, "one-step"/"loop"
        calign_flag        % the color aligned flag, true or false
        bkgextract_flag    % the signal channel background extract flag
        isplaying          % flag for movie is playing, true/false
        fps                % the frames per second when viewing, struct with {base,fold}

        % image tools handles
        im_handle          % the handle of UIAxes_Slice
        roi_handle         % the current roi handle, image handle
        time_text          % the time text info struct, {handle, text, ismoving}
        meta_text          % the meta text info struct, {handle, text, ismoving}
        scalebar           % the scale bar info struct, {handle_x, handle_y, txtobj_x, txtobj_y, scale, bar, ismoving}
        % text_moving        % text moving flag, true for text is moving free, false for locked

        % computer configurations
        filename           % record the processing file name
        varname            % record the processing variable name
        sv_path            % the file saving path
        is_server          % the flag for estimating if running on server
        region_info        % the region setting struct, {plat, lang, mapping}

        % subapp handles
        BC_handle          % brightness and contrast setting, sub app handle
        EV_handle          % the evaluation handle
        MG_handle          % the mask generator handle
        RS_handle          % the registration setting handle
        SB_handle          % the scale bar setting handle
        FS_handle          % the frames selecter handle
        EM_handle          % the movie exporter handle
        CA_handle          % the channel alignment handle
        BR_handle          % the bug reporter handle

        % system management tools/flags
        % error_msg          % inner error message
        template_sync      % the template synchronize mode, "global"/"local"
        player_timer         % timer for viewing animation
        checker_timer      % timer for checking the system status
        is_data_loaded     % flag for data loading success
        is_data_masked     % flag for data masked
        reg_require        % the registration requirement, "开"/"关"
        img_operators      % string array for recording operations
        system_message     % record system messages history, [cmd, sfx, time]
        mdrp               % the masked data recovery point
        update_opts        % the options for update app
        sftp_to_labnas     % the handle of sftp object to labnas
    end
    
    %=========================== APP METHODS =============================
    methods (Access = private)
        %====================== INIT COMPONENTS ==========================
        
        function InitData(app)
            % init movie info
            app.mov_raw = regmov.empty();
            app.mov_aligned = regmov.empty();
            app.tprojimg = struct("raw",[], "aligned",[]);
            app.zprojimg = struct("raw",[], "aligned",[]);

            % init ROI 
            app.roi_cur = struct("xy",[],"z",[],"f",[]);

            % init app private variables
            app.frame_idx_cur = [];
            app.slice_idx_cur = [];
            app.channel_idx_cur = [];
            app.sliceimg_cur = [];
            app.channel_order = [];
            app.view_opts = struct("b",0,"c",1,"ga",1);
            app.im_handle = [];
            app.roi_handle = [];
            app.time_text = struct("handle",[],"text",[],"ismoving",[]);
            app.meta_text = struct("handle",[],"text",[],"ismoving",[]);
            app.scalebar = struct("handle_x",[],...   % line object
                "handle_y",[],...
                "txtobj_x",[],...  % text object
                "txtobj_y",[],...
                "scale",struct("xr",[],"yr",[],"unit","um"),...
                "bar",struct("xlen",10,"ylen",10,"ishor",false,"isver",false,...
                "linw",1,"color","w","mk","none","sx",40,"sy",40,...
                "mk_fill",true,"mk_size",3,"txt_space",8,...
                "font",struct("FontName",'Arial',"FontWeight",'bold',...
                              "FontAngle",'normal',"FontUnits",'points',"FontSize",10)),...
                "ismoving",[],"islocking",false,"sf",1);
            app.zproj_mode = "none";
            app.merge_mode = "";
            app.calign_flag = false;
            app.bkgextract_flag = false;
            app.viewer_src = "";
            app.reg_require = "none";
            app.reg_opts = struct();    % init
            app.reg_frames = [];
            app.isplaying = false;
            app.fps = struct("fold",1,"base",[]);
            app.sv_path = "";

            app.template_sync = "global";
            app.is_data_loaded = false;
            app.is_data_masked = false;
            app.img_operators = "none";
            app.mdrp = struct("raw",[],"aligned",[]);
            app.update_opts = struct("is_update",false,"method","manual",...
                "repository","","auto_clean",false);
        end

        function results = DeployEnvConfig(app)
            % check java path
            % check C/C++ compiler
            javachk('jvm');
            javachk('swing');


            if ispc()
                mex -setup C
                if GetAvailableMemory() <= 2.7e10
                    app.memmap_flag = true;
                else
                    app.memmap_flag = false;
                end
            elseif isunix()
                app.memmap_flag = true;
            end
            pause(0.5);
            clc;

            if (feature('numCores')) >= 16 && (GetAvailableMemory()>1.375e11)
                % no less that 16 cores and 128GB available memory
                app.is_server = true;
                disp("Server mode enabled: too many CPU cores and memory installed.");
            else
                app.is_server = false;
            end

            results = 0;
        end

        function InitTimer(app)
            % init player timer
            app.player_timer = timer("Period",1,...
                "ExecutionMode","fixedSpacing","TasksToExecute",inf,...
                "Name","Timer_PlayControl","TimerFcn",@app.Play,"StopFcn",@app.Stop);

            % init the system timer, checking system status with period 1s
            app.checker_timer = timer("Period",1,...
                "ExecutionMode","fixedSpacing","TasksToExecute",inf,...
                "Name","Timer_SystemCheck","TimerFcn",@app.RegController);
        end

        function InitRegOptions(app)
            % init the registration options
            % init common parameters
            % ============ default registration type ==================
            app.reg_opts.RegType = "auto";

            % ============ automatic registration parameters ===========
            app.reg_opts.volfixed = struct("G", ["mean", "1"],...
                "L",  ["mean", "1"]);
            app.reg_opts.RegMode = "none";   % init as none, for no mode
            app.reg_opts.RegModal = "monomodal";
            
            app.reg_opts.AutoTform = "translation";
            app.reg_opts.InitStep = 0.1;
            app.reg_opts.MinStep = 1e-4;
            app.reg_opts.MaxIterN_Rigid = 50;
            app.reg_opts.MaxIterN_NonRigid = 100;
            app.reg_opts.IterCoeff = 0.5;
            app.reg_opts.AFS = 1.0;
            app.reg_opts.VPL_Rigid = floor(log(...
                app.mov_raw.MetaData.slices)/log(4))+1;
            app.reg_opts.VPL_NonRigid = app.reg_opts.VPL_Rigid;
            app.reg_opts.Interp_Rigid = "cubic";
            app.reg_opts.Interp_NonRigid = "cubic";

            % =========== manual registration parameters ==============
            app.reg_opts.ManualTform = "nonreflectivesimilarity";
            app.reg_opts.Degree = 3;
            app.reg_opts.Nlwm = 12;
            app.reg_opts.ManualInterp = "cubic";
            app.reg_opts.ManualEdgeSmooth = true;

            % init specific parameters
            switch numel(app.channel_order)
                case 1
                    % ==== automatic registration parameters (1 channel) ====
                    app.reg_opts.RL = "ORN";
                    app.reg_opts.GA = 2.0;
                    app.reg_opts.InsTh = 2.0;
                    app.reg_opts.ScaleTh = 2.0;
                    app.reg_opts.GridUnit = "auto";
                case 2
                    % ==== automatic registration parameters (2 channels) ====
                    app.reg_opts.AutoContrast = false;
                    app.reg_opts.CompAcc = 1024;
                    app.reg_opts.CoRegC = 29;
                    app.reg_opts.DS = "auto";
                    app.reg_opts.Chla = "r";
                    app.reg_opts.Chls = "g";
                otherwise
                    error(app.region_info.mapping.UNSUPPORTED_CHANNEL_NUMBER);
            end

            % ============ long-term registration parameters =============
            app.reg_opts.KeyFrames = "auto";
            app.reg_opts.IntensityThreshold = 97;
            app.reg_opts.ScaleThreshold = 3;
            app.reg_opts.DS_PointCloud = "GA";
            app.reg_opts.DS_Voxel = "2X2";
            app.reg_opts.DS_PointCloud_Param = 2;
            app.reg_opts.OutlierRatio = 0.1;
            app.reg_opts.MaxIterN_PointCloud = 50;
            app.reg_opts.ErrorLimit = 1e-5;
            app.reg_opts.InitStep_LongTerm = 6.25e-2;
            app.reg_opts.MinStep_LongTerm = 1e-5;
            app.reg_opts.MaxIterN_Voxel = [100,50,25];
            app.reg_opts.IterCoeff_LongTerm = 0.5;
            app.reg_opts.AutoContrast_LongTerm = true;
            app.reg_opts.CompAcc_LongTerm = 1024;
            app.reg_opts.AFS_LongTerm = 1.0;
        end

        function InitPanels(app)
            InitMenu(app);

            InitSlider_Slice(app);

            InitSlider_Time(app);

            InitContextMenu(app);

            InitControlPanel(app);

            InitUIAxes(app);

            InitSubPanels(app);
        end

        function InitSubPanels(app)
            % apply sub app language
            if ~isempty(app.BC_handle) && isvalid(app.BC_handle)
                delete(app.BC_handle);
                app.BC_handle = [];
            end
            if ~isempty(app.EV_handle) && isvalid(app.EV_handle)
                delete(app.EV_handle);
                app.EV_handle = [];
            end
            if ~isempty(app.MG_handle) && isvalid(app.MG_handle)
                delete(app.MG_handle);
                app.MG_handle = [];
            end
            if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                delete(app.RS_handle);
                app.RS_handle = [];
            end
            if ~isempty(app.SB_handle) && isvalid(app.SB_handle)
                delete(app.SB_handle);
                app.SB_handle = [];
            end
            if ~isempty(app.FS_handle) && isvalid(app.FS_handle)
                delete(app.FS_handle);
                app.FS_handle = [];
            end
            if ~isempty(app.EM_handle) && isvalid(app.EM_handle)
                delete(app.EM_handle);
                app.EM_handle = [];
            end
            if ~isempty(app.BR_handle) && isvalid(app.BR_handle)
                delete(app.BR_handle);
                app.BR_handle = [];
            end
        end

        function SetSystemLanguage(app, lang)
            if exist("lang","var") && ~isempty(lang)
                UpdateRegion(app, lang);
            else
                app.region_info = GetRegionInfo();
                UpdateRegion(app, app.region_info.lang);
            end

            % apply menu language
            app.Menu_File.Text = app.region_info.mapping.FILE;
            app.Menu_Image.Text = app.region_info.mapping.IMAGE;
            app.Menu_Options.Text = app.region_info.mapping.OPTION;
            app.Menu_Help.Text = app.region_info.mapping.HELP;
            app.Menu_LoadFile.Text = app.region_info.mapping.LOAD_FILE;
            app.Menu_LoadMem.Text = app.region_info.mapping.LOAD_WORKSPACE_VARIABLE;
            app.Menu_SaveImageAs.Text = app.region_info.mapping.EXPORT_IMAGES;
            app.Menu_SaveGeoTformAs.Text = app.region_info.mapping.EXPORT_GEOMETRIC_TRANSFORM;
            app.Menu_SaveTimeAs.Text = app.region_info.mapping.EXPORT_TIME;
            app.Menu_SaveMovieAs.Text = app.region_info.mapping.EXPORT_MOVIE;
            app.Menu_Quit.Text = app.region_info.mapping.EXIT;
            app.Menu_LightContrast.Text = app.region_info.mapping.LIGHT_CONTRAST;
            app.Menu_ChannelAlignment.Text = app.region_info.mapping.CHANNEL_ALIGNMENT;
            app.Menu_RemoveBackGround.Text = app.region_info.mapping.REMOVE_BACKGROUND;
            app.Menu_RemoveBackgroundAuto.Text = app.region_info.mapping.AUTO;
            app.Menu_RemoveBackgroundFix.Text = app.region_info.mapping.FIXED;
            app.Menu_ZProjection.Text = app.region_info.mapping.Z_PROJECTION;
            app.Menu_MaskManager.Text = app.region_info.mapping.MASK;
            app.Menu_Comments.Text = app.region_info.mapping.APPEND_COMMENTS;
            app.Menu_Crop3D.Text = app.region_info.mapping.CROP_VOLUME;
            app.Menu_CropT.Text = app.region_info.mapping.CROP_FRAME;
            app.Menu_SaveSetting.Text = app.region_info.mapping.EXPORT_CONFIGURATION;
            app.Menu_LoadSetting.Text = app.region_info.mapping.LOAD_CONFIGURATION;
            app.Menu_Animation.Text = app.region_info.mapping.ANIMATION;
            app.Menu_KeyMode.Text = app.region_info.mapping.PLAY_MODE;
            app.Menu_Register.Text = app.region_info.mapping.REGISTRATOR;
            app.Menu_RegHistory.Text = app.region_info.mapping.REGISTRATION_HISTORY;
            app.Menu_RunModal.Text = app.region_info.mapping.RUN_MODAL;
            app.Menu_RunModalDistributed.Text = app.region_info.mapping.DISTRIBUTED;
            app.Menu_RunModalExclusive.Text = app.region_info.mapping.EXCLUSIVE;
            app.Menu_About.Text = app.region_info.mapping.ABOUT;
            app.Menu_Documents.Text = app.region_info.mapping.DOCUMENT;
            app.Menu_Language.Text = app.region_info.mapping.LANGUAGE;
            app.Menu_Update.Text = app.region_info.mapping.CHECK_UPDATE;
            app.Menu_SubmitBug.Text = app.region_info.mapping.SUBMIT_BUG;
            app.Menu_MaxZProjection.Text = app.region_info.mapping.MAX_Z_PROJECTION;
            app.Menu_MinZProjection.Text = app.region_info.mapping.MIN_Z_PROJECTION;
            app.Menu_MedianZProjection.Text = app.region_info.mapping.MEDIAN_Z_PROJECTION;
            app.Menu_MeanZProjection.Text = app.region_info.mapping.MEAN_Z_PROJECTION;
            app.Menu_GenerateMask.Text = app.region_info.mapping.GENERATE_MASK;
            app.Menu_LoadMask.Text = app.region_info.mapping.IMPORT_MASK;
            app.Menu_TimeStamp.Text = app.region_info.mapping.TIME_STAMP;
            app.Menu_ScaleBar.Text = app.region_info.mapping.SCALE_RATIO;
            app.Menu_MetaMarker.Text = app.region_info.mapping.METADATA;
            app.Menu_CropXY.Text = app.region_info.mapping.CROP_XY;
            app.Menu_CropZ.Text = app.region_info.mapping.CROP_Z;
            app.Menu_Direct.Text = app.region_info.mapping.DIRECT;
            app.Menu_Loop.Text = app.region_info.mapping.RECYCLE;
            app.Menu_RegOptions.Text = app.region_info.mapping.REGISTRATION_SETTING;
            app.Menu_RegRange.Text = app.region_info.mapping.REGISTRATION_RANGE;
            app.Menu_RegEvaluate.Text = app.region_info.mapping.REGISTRATION_EVALUATION;

            % apply main panel language
            app.Panel_SliceViewer.Title = app.region_info.mapping.SLICE_VIEWER;
            app.Panel_Control.Title = app.region_info.mapping.CONTROL_PANEL;
            app.Label_2.Text = app.region_info.mapping.SLICE;
            app.Label.Text = app.region_info.mapping.TIME_FRAME;
            app.Label_19.Text = app.region_info.mapping.SOURCE;
            app.Label_20.Text = app.region_info.mapping.LOG;
            app.Switch_Log.Items = [app.region_info.mapping.OFF, app.region_info.mapping.ON];
            if numel(app.DropDown_Source.Items) == 1
                app.DropDown_Source.Items = app.region_info.mapping.RAW;
            elseif numel(app.DropDown_Source.Items) == 2
                app.DropDown_Source.Items = [app.region_info.mapping.RAW, ...
                                             app.region_info.mapping.ALIGNED];
            end
            app.Button_Run.Text = app.region_info.mapping.RUN;

            % apply static context menu language
            app.Menu_Horizontal.Text = app.region_info.mapping.HORIZONTAL_FLIP;
            app.Menu_Vertical.Text = app.region_info.mapping.VERTICAL_FLIP;
            app.Menu_ROI.Text = app.region_info.mapping.ROI_SETTING;
            app.Menu_Channel.Text = app.region_info.mapping.COLOR_CHANNEL;
            app.Menu_RectangleROI.Text = app.region_info.mapping.RECTANGLE;
            app.Menu_CircleROI.Text = app.region_info.mapping.CIRCLE;
            app.Menu_PolygonROI.Text = app.region_info.mapping.POLYGON;
            app.Menu_FreehandROI.Text = app.region_info.mapping.REGION_FREE;
            app.Menu_ExportLog.Text = app.region_info.mapping.EXPORT;
            app.Menu_ClearLog.Text = app.region_info.mapping.CLEAR;

            % apply dynamic context menu language
            if ~isempty(app.Menu_MergeSource) && isvalid(app.Menu_MergeSource)
                app.Menu_MergeSource.Text = app.region_info.mapping.MERGE_SOURCE;
                app.Menu_MergeNone.Text = app.region_info.mapping.NONE;
                app.Menu_MergeRB.Text = app.region_info.mapping.RED_CYAN;
                app.Menu_MergeGR.Text = app.region_info.mapping.GREEN_MAGENTA;
            end
            if ~isempty(app.Menu_Red) && isvalid(app.Menu_Red)
                app.Menu_Red.Text = app.region_info.mapping.RED;
            end
            if ~isempty(app.Menu_Green) && isvalid(app.Menu_Green)
                app.Menu_Green.Text = app.region_info.mapping.GREEN;
            end

            % translate the log message
            if ~isempty(app.TextArea_Log.Value{1}) && app.TextArea_Log.Value{1} ~= ""
                if exist("lang","var") && ~isempty(lang)
                    for k = 1:numel(app.TextArea_Log.Value)
                        if app.system_message(k, 3) ~= ""
                            app.TextArea_Log.Value{k} = [app.system_message(k, 3).char(), ...
                                ' ', app.region_info.mapping.(app.system_message(k, 1)).char(), ...
                                app.system_message(k, 2).char()];
                        else
                            app.TextArea_Log.Value{k} = ...
                                [app.region_info.mapping.(app.system_message(k, 1)).char(), ...
                                 app.system_message(k, 2).char()];
                        end
                    end
                end
            end

            % apply sub app language
            if ~isempty(app.BC_handle) && isvalid(app.BC_handle)
                app.BC_handle.RefreshPanelLanguage();
            end
            if ~isempty(app.EV_handle) && isvalid(app.EV_handle)
                app.EV_handle.RefreshPanelLanguage();
            end
            if ~isempty(app.MG_handle) && isvalid(app.MG_handle)
                app.MG_handle.RefreshPanelLanguage();
            end
            if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                app.RS_handle.RefreshPanelLanguage();
            end
            if ~isempty(app.SB_handle) && isvalid(app.SB_handle)
                app.SB_handle.RefreshPanelLanguage();
            end
            if ~isempty(app.FS_handle) && isvalid(app.FS_handle)
                app.FS_handle.RefreshPanelLanguage();
            end
            if ~isempty(app.EM_handle) && isvalid(app.EM_handle)
                app.EM_handle.RefreshPanelLanguage();
            end
            if ~isempty(app.BR_handle) && isvalid(app.BR_handle)
                app.BR_handle.RefreshPanelLanguage();
            end

        end

        function InitMenu(app)
            app.Menu_SaveImageAs.Enable = "off";
            app.Menu_SaveTimeAs.Enable = "off";
            app.Menu_SaveGeoTformAs.Enable = "off";
            app.Menu_SaveMovieAs.Enable = "off";
            app.Menu_Image.Enable = "off";
            app.Menu_RemoveBackgroundAuto.Checked = "off";
            app.Menu_RemoveBackgroundFix.Checked = "off";
            app.Menu_RegOptions.Enable = "off";
            app.Menu_RegEvaluate.Enable = "off";
            app.Menu_RegRange.Enable = "off";
            app.Menu_Register.Enable = "off";
            app.Menu_RegHistory.Enable = "off";
            app.Menu_Animation.Enable = "off";
            app.Menu_MaxZProjection.Checked = "off";
            app.Menu_MinZProjection.Checked = "off";
            app.Menu_MeanZProjection.Checked = "off";
            app.Menu_MedianZProjection.Checked = "off";

            app.Menu_KeyMode.Enable = "off";
            app.Menu_Direct.Checked = "on";
            app.Menu_Loop.Checked = "off";
            app.Menu_1F.Checked = "on";
            app.Menu_5F.Checked = "off";
            app.Menu_25F.Checked = "off";
            switch app.region_info.lang
                case "zh_CN"
                    app.Menu_Chinese.Checked = "on";
                case "en_US"
                    app.Menu_English.Checked = "on";
                case "ru_RU"
                    app.Menu_Russian.Checked = "on";
                case "fr_FR"
                    app.Menu_French.Checked = "on";
                case "es_ES"
                    app.Menu_Spanish.Checked = "on";
                otherwise
                    app.Menu_English.Checked = "on";
            end

            if isunix(); app.Menu_SubmitBug.Enable = "off"; end

            if app.memmap_flag == true || app.is_server == true
                % unix or server
                app.Menu_RunModalDistributed.Checked = "on";
                app.Menu_RunModalExclusive.Checked = "off";
            else
                % high performance pc(Windows), exclusive is possible
                app.Menu_RunModalDistributed.Checked = "off";
                app.Menu_RunModalExclusive.Checked = "on";
            end
        end

        function InitContextMenu(app)
            app.Menu_Horizontal.Enable = "off";
            app.Menu_Vertical.Enable = "off";
            app.Menu_ROI.Enable = "off";
            app.Menu_Channel.Enable = "off";

            % dynamic context menu
            RemoveMergeChannelMenu(app);
            RemoveColorChannelMenu(app);
        end

        function InitSlider_Time(app)
            app.Slider_Time.Limits = [0,1];
            app.Slider_Time.Value = 0;
            app.Slider_Time.Enable = "off";
            app.Spinner_Time.Enable = "off";
        end

        function InitControlPanel(app)
            app.DropDown_Source.Enable = "off";
            app.Button_Run.Enable = "off";
            app.key_mode = "one-step";
        end

        function InitUIAxes(app)
            app.UIAxes_Slice.Visible = "off";
        end

        function InitSlider_Slice(app)
            app.Slider_Slice.Limits = [0,1];
            app.Slider_Slice.Value = 0;
            app.Slider_Slice.Enable = "off";
            app.Spinner_Slice.Enable = "off";
        end
        
        function im_H = InitUIAxes_Slice(app)
            app.UIAxes_Slice.Toolbar = [];
            % disable main UIAxes interaction to avoid mistake operation
            disableDefaultInteractivity(app.UIAxes_Slice);

            app.UIAxes_Slice.XLim = [0, app.mov_raw.MetaData.width];
            app.UIAxes_Slice.YLim = [0, app.mov_raw.MetaData.height];

            pbaspect(app.UIAxes_Slice, [app.mov_raw.MetaData.width, ...
                                        app.mov_raw.MetaData.height,...
                                        1]);

            im_H = image(app.UIAxes_Slice,...
                [0 app.mov_raw.MetaData.width],...
                [0 app.mov_raw.MetaData.height],...
                [],"Interpolation","bilinear"); % bilinear offer better visual effort

            app.im_handle = im_H;

            colormap(app.UIAxes_Slice,"gray");
            drawnow

            UpdateYScrollLim(app);

            app.UIAxes_Slice.Visible = "on";
        end
        
        %======================= PLAYER CONTROL ==========================
        function UpdateYScrollLim(app)
            % calculate scroll y limitation
            tp = app.UIAxes_Slice.tightPosition();
            % xr = diff(app.UIAxes_Slice.XLim)/tp(3)*app.Panel_Control.Position(3);
            yr = diff(app.UIAxes_Slice.YLim)/tp(4)*app.Panel_SliceViewer.Position(4);
            % xvalid = 0.5*diff(app.UIAxes_Slice.XLim) + 0.5*[-xr, xr];
            tiset = app.UIAxes_Slice.TightInset;
            app.scroll_ylim = 0.5*diff(app.UIAxes_Slice.YLim) + ...
                0.5*[-yr, yr] + 0.5*[tiset(2)+20, tiset(4)];
        end

        function UpdatePlayerTimer(app, nframes, fps)
            if ~isempty(app.player_timer) && isvalid(app.player_timer)
                app.player_timer.Period = 1/fps;
                app.player_timer.TasksToExecute = nframes;
            end
        end
        
        function NextFramePtr(app)
            if ~isempty(app.frame_idx_cur) && app.calign_flag == false
                if app.frame_idx_cur < app.Slider_Time.Limits(end)
                    app.frame_idx_cur = app.frame_idx_cur + 1;
                else
                    switch app.key_mode
                        case "one-step"
                            % do nothing
                        case "loop"
                            app.frame_idx_cur = app.Slider_Time.Limits(1);
                    end
                end
                app.Slider_Time.Value = app.frame_idx_cur;
                app.Spinner_Time.Value = app.frame_idx_cur;
            end
        end

        function PrevFramePtr(app)
            if ~isempty(app.frame_idx_cur) && app.calign_flag == false
                if app.frame_idx_cur > app.Slider_Time.Limits(1)
                    app.frame_idx_cur = app.frame_idx_cur - 1;
                else
                    switch app.key_mode
                        case "one-step"
                            % do nothing
                        case "loop"
                            app.frame_idx_cur = app.Slider_Time.Limits(end);
                    end
                end
                app.Slider_Time.Value = app.frame_idx_cur;
                app.Spinner_Time.Value = app.frame_idx_cur;
            end
        end

        function NextSlicePtr(app)
            if ~isempty(app.slice_idx_cur) && app.zproj_mode == "none" ...
                    && app.calign_flag == false
                if app.slice_idx_cur < app.Slider_Slice.Limits(end)
                    app.slice_idx_cur = app.slice_idx_cur + 1;
                else
                    switch app.key_mode
                        case "one-step"
                            % do nothing
                        case "loop"
                            app.slice_idx_cur = app.Slider_Slice.Limits(1);
                    end
                end
                app.Slider_Slice.Value = app.slice_idx_cur;
                app.Spinner_Slice.Value = app.slice_idx_cur;
            end
        end

        function PrevSlicePtr(app)
            if ~isempty(app.slice_idx_cur) && app.zproj_mode == "none" ...
                    && app.calign_flag == false
                if app.slice_idx_cur > app.Slider_Slice.Limits(1)
                    app.slice_idx_cur = app.slice_idx_cur - 1;
                else
                    switch app.key_mode
                        case "one-step"
                            % do nothing
                        case "loop"
                            app.slice_idx_cur = app.Slider_Slice.Limits(end);
                    end
                end
                app.Slider_Slice.Value = app.slice_idx_cur;
                app.Spinner_Slice.Value = app.slice_idx_cur;
            end
        end

        function Play(app, obj, event)
            obj; %#ok<VUNUS>
            event; %#ok<VUNUS>
            if ~app.calign_flag
                NextFramePtr(app);
                UpdateCurrentSlice(app);    % omit the oriented color channel
                UpdateUIAxes_Slice(app);

                % follow the mouse
                % Reg3DV200WukongUIFigureWindowButtonMotion(app);
            end
        end

        function Stop(app, obj, event)
            obj; %#ok<VUNUS>
            event; %#ok<VUNUS>
            UnlockPlaySetting(app);
        end

        function RefreshViewer(app)
            if ~app.calign_flag
                UpdateCurrentSlice(app);
                UpdateUIAxes_Slice(app);
            end
        end

        function LockViewerController(app)
            app.Slider_Slice.Enable = "off";
            app.Spinner_Slice.Enable = "off";
            app.Slider_Time.Enable = "off";
            app.Spinner_Time.Enable = "off";
            app.Menu_Animation.Enable = "off";
            app.Menu_Horizontal.Enable = "off";
            app.Menu_Vertical.Enable = "off";
            app.Menu_Channel.Enable = "off";
        end

        function UnlockViewerController(app)
            if app.zproj_mode == "none"
                app.Slider_Slice.Enable = "on";
                app.Spinner_Slice.Enable = "on";
            end
            app.Slider_Time.Enable = "on";
            app.Spinner_Time.Enable = "on";
            app.Menu_Animation.Enable = "on";
            app.Menu_Horizontal.Enable = "on";
            app.Menu_Vertical.Enable = "on";
            app.Menu_Channel.Enable = "on";
        end
        
        %===================== PLAYER SETTING ============================
        
        % player components: lock play mode menu
        function LockPlaySetting(app)
            app.Menu_Loop.Enable = "off";
            app.Menu_Direct.Enable = "off";
            app.Menu_1F.Enable = "off";
            app.Menu_5F.Enable = "off";
            app.Menu_25F.Enable = "off";
        end

        % player components: unlock play mode menu
        function UnlockPlaySetting(app)
            app.Menu_Loop.Enable = "on";
            app.Menu_Direct.Enable = "on";
            app.Menu_1F.Enable = "on";
            app.Menu_5F.Enable = "on";
            app.Menu_25F.Enable = "on";
        end

        % player components: time slider
        function SetSlider_Time(app,state,limits,labelCounts)
            if ~exist("labelCounts","var")
                labelCounts = 20;
            end
            if ~isempty(state)
                switch state
                    case 'on'
                        app.Slider_Time.Enable = "on";
                        app.Spinner_Time.Enable = "on";
                    case 'off'
                        app.Slider_Time.Limits = [0,1];
                        app.Slider_Time.Value = 0;
                        app.Slider_Time.Enable = "off";
                        app.Spinner_Time.Limits = [0,1];
                        app.Spinner_Time.Value = 0;
                        app.Spinner_Time.Enable = "off";
                    case 'freeze'
                        app.Slider_Time.Enable = "off";
                        app.Spinner_Time.Enable = "off";
                end
            else
                % keep the state
            end

            if ~isempty(app.mov_raw) && app.Slider_Time.Enable == true
                if ~isempty(limits)
                    app.Slider_Time.Limits = limits;
                    app.Spinner_Time.Limits = limits;
                else
                    % keep the limits
                    limits = app.Slider_Time.Limits;
                end

                deltaT = round((limits(2)-limits(1))/labelCounts);
                if ~isequal(app.Slider_Time.MajorTicks,limits(1):deltaT:limits(2))
                    app.Slider_Time.MajorTicks = limits(1):deltaT:limits(2);
                    app.Slider_Time.MajorTickLabels = string(app.Slider_Time.MajorTicks);
                end

                app.Slider_Time.Value = app.frame_idx_cur;
                app.Spinner_Time.Value = app.frame_idx_cur;
            end
        end

        % player components: slice slider
        function SetSlider_Slice(app,state)
            if exist("state","var") && ~isempty(state)
                switch state
                    case 'on'
                        app.Slider_Slice.Enable = "on";
                        app.Spinner_Slice.Enable = "on";
                    case 'off'
                        app.Slider_Slice.Limits = [0,1];
                        app.Slider_Slice.Value = 0;
                        app.Slider_Slice.Enable = "off";
                        app.Spinner_Slices.Limits = [0,1];
                        app.Spinner_Slices.Value = 0;
                        app.Spinner_Slice.Enable = "off";
                    case 'freeze'
                        app.Slider_Slice.Enable = "off";
                        app.Spinner_Slice.Enable = "off";
                end
            else
                % keep the state
            end

            if ~isempty(app.mov_raw)
                app.Slider_Slice.Limits = [1, app.mov_raw.MetaData.slices];
                app.Spinner_Slice.Limits = [1, app.mov_raw.MetaData.slices];

                app.Slider_Slice.MajorTicks = 1:5:app.mov_raw.MetaData.slices;
                app.Slider_Slice.MinorTicks = 1:app.mov_raw.MetaData.slices;

                app.Slider_Slice.Value = app.slice_idx_cur;
                app.Spinner_Slice.Value = app.slice_idx_cur;
            end
        end

        % player components: viewer window
        function UpdateUIAxes_Slice(app)
            if ~isempty(app.sliceimg_cur)
                % update the world coordinate
                xlim(app.UIAxes_Slice,[0,app.mov_raw.MetaData.width]);
                ylim(app.UIAxes_Slice,[0,app.mov_raw.MetaData.height]);

                % primary version for fast rendering
                % fill the new image
                set(app.im_handle,'CData',app.sliceimg_cur);

                % reshape the im_handle (image shape)
                set(app.im_handle,'XData',[0,app.mov_raw.MetaData.width]);
                set(app.im_handle,'YData',[0,app.mov_raw.MetaData.height]);

                % keep the width-height ratio
                pbaspect(app.UIAxes_Slice,[size(app.sliceimg_cur,[2,1]),1]);

                % update the text
                if ~isempty(app.time_text.handle) && isvalid(app.time_text.handle)
                    app.time_text.handle.String = ...
                        app.time_text.text(app.frame_idx_cur);
                end
                if ~isempty(app.meta_text.handle) && isvalid(app.meta_text.handle)
                    app.meta_text.handle.String = ...
                        app.meta_text.text{app.frame_idx_cur};
                end

                % update image immediately
                drawnow nocallbacks
            end
        end

        % player components: viewer title
        function UpdateUIAxes_Slice_Title(app,cursorPos,grayVal,roi)
            if ~exist("cursorPos","var") || isempty(cursorPos)
                cursorPos = 'None';
            else
                cursorPos = ['(',num2str(cursorPos(1)),',',...
                    num2str(cursorPos(2)),')'];
            end
            if ~exist("grayVal","var") || isempty(grayVal)
                grayVal = 'None';
            else
                grayVal = num2str(grayVal);
            end
            if ~exist("roi","var") || isempty(roi)
                roi = "None";
            else
                roi = ['w = ',num2str(roi(1)),', h = ',num2str(roi(2))];
            end

            app.UIAxes_Slice.Title.String = "Cursor: " ...
                + cursorPos + ",   Value: " + grayVal...
                + ",   ROI: " + roi;
        end

        % player components: dynamic add merge channel
        function AppendMergeChannelMenu(app)
            % construct the parent menu
            app.Menu_MergeSource = uimenu(app.ContextMenu_Player);
            app.Menu_MergeSource.Enable = "on";
            app.Menu_MergeSource.Text = app.region_info.mapping.MERGE_SOURCE;

            % construct the children menu
            app.Menu_MergeNone = uimenu(app.Menu_MergeSource);
            app.Menu_MergeNone.MenuSelectedFcn = createCallbackFcn(app, @Menu_MergeNoneSelected, true);
            app.Menu_MergeNone.Enable = "on";
            app.Menu_MergeNone.Text = app.region_info.mapping.NONE;
            app.Menu_MergeNone.Checked = "on";
            %
            app.Menu_MergeRB = uimenu(app.Menu_MergeSource);
            app.Menu_MergeRB.MenuSelectedFcn = createCallbackFcn(app, @Menu_MergeRBSelected, true);
            app.Menu_MergeRB.Enable = "on";
            app.Menu_MergeRB.Text = app.region_info.mapping.RED_CYAN;
            app.Menu_MergeRB.Checked = "off";
            %
            app.Menu_MergeGR = uimenu(app.Menu_MergeSource);
            app.Menu_MergeGR.MenuSelectedFcn = createCallbackFcn(app, @Menu_MergeGRSelected, true);
            app.Menu_MergeGR.Enable = "on";
            app.Menu_MergeGR.Text = app.region_info.mapping.GREEN_MAGENTA;
            app.Menu_MergeGR.Checked = "off";
        end

        % player components: dynamic remove merge channel
        function RemoveMergeChannelMenu(app)
            if ~isempty(app.Menu_MergeSource) ...
                    && isvalid(app.Menu_MergeSource)
                delete(app.Menu_MergeSource);
            end
        end

        % player components: dynamic add color channel
        function AppendColorChannelMenu(app)
            if ismember("r", app.channel_order)
                app.Menu_Red = uimenu(app.Menu_Channel);
                app.Menu_Red.MenuSelectedFcn = createCallbackFcn(app, @Menu_RedSelected, true);
                app.Menu_Red.Enable = "on";
                app.Menu_Red.Text = app.region_info.mapping.RED;
                app.Menu_Red.Checked = "off";
            end
            if ismember("g", app.channel_order)
                app.Menu_Green = uimenu(app.Menu_Channel);
                app.Menu_Green.MenuSelectedFcn = createCallbackFcn(app, @Menu_GreenSelected, true);
                app.Menu_Green.Enable = "on";
                app.Menu_Green.Text = app.region_info.mapping.GREEN;
                app.Menu_Green.Checked = "off";
            end
            switch app.channel_order(app.channel_idx_cur)
                case "r"
                    app.Menu_Red.Checked = "on";
                case "g"
                    app.Menu_Green.Checked = "on";
                otherwise
                    error(app.region_info.mapping.UNSUPPORTED_CHANNEL_NUMBER);
            end
        end

        % player components: dynamic remove color channel
        function RemoveColorChannelMenu(app)
            if ~isempty(app.Menu_Red) && isvalid(app.Menu_Red)
                delete(app.Menu_Red);
            end
            if ~isempty(app.Menu_Green) && isvalid(app.Menu_Green)
                delete(app.Menu_Green);
            end
        end

        %==================== CONTROL PANEL SETTING ======================

        function ClearTextArea_Log(app)
            % clear log buffer
            if ~isempty(app.TextArea_Log.Value)
                app.TextArea_Log.Value = "";
            end
        end

        function ExportTextArea_Log(app, path)
            t = datetime('now','TimeZone','local','Format','yyMMdd_HHmmss');
            fname = fullfile(path, "log_"+string(t)+".txt");
            writecell(app.TextArea_Log.Value, fname);
        end

        %====================== INDICATE SET/GET =========================

        function status = GetSystemState(app)
            % status:
            %
            if isempty(app.mov_raw)
                status = app.SOURCE_NOT_READY;
                return;
            end
            if app.reg_opts.RegMode == "none"
                status = app.REGMODE_NOT_READY;
                return;
            end
            status = app.REG_READY;
        end

        function timeMsg = AppendTextArea_Log(app, msg, usingTimeStamp)
            if app.Switch_Log.Value == "off"
                return;
            end

            if ~exist('usingTimeStamp','var')
                usingTimeStamp = false;
            end

            if usingTimeStamp == true
                % Append message on text area
                timeMsg =string(datetime("now")).char();
                Msg = [timeMsg, ' ', char(msg)];
            else
                timeMsg = '';
                Msg = char(msg);
            end
            if isempty(app.TextArea_Log.Value{1})
                app.TextArea_Log.Value={Msg};
            else
                app.TextArea_Log.Value = [app.TextArea_Log.Value;{Msg}];
            end

            % scroll to bottom auto
            scroll(app.TextArea_Log, "bottom");
        end

        %========================== DATA UPDATE ==========================

        function status = MemLoader(app, memptr, opts, rt)
            % This function load data to app workspace
            if isempty(opts) || isempty(memptr)
                status = -1;
                return;
            end

            if isempty(rt)
                rt = 1:opts.frames;
            end

            if opts.frames < 2
                uialert(app.Reg3DV200WukongUIFigure,...
                    app.region_info.mapping.UNSUPPORTED_FEW_FRAMES,...
                    app.region_info.mapping.WARNING,"Icon","warning");
                status = -2;
                return;
            end

            if opts.slices < 5
                uialert(app.Reg3DV200WukongUIFigure,...
                    app.region_info.mapping.UNSUPPORTED_FEW_LAYERS,...
                    app.region_info.mapping.WARNING,"Icon","warning");
                status = -3;
                return;
            end

            InitData(app);

            PanelWaiting(app);  %

            app.mov_raw = regmov(memptr, opts, rt, zeros(1,numel(opts.cOrder)));

            app.channel_order = opts.cOrder;

            % init start volume at channel 1
            app.channel_idx_cur = 1;

            % initialize registration and view parameters
            app.slice_idx_cur = 1;
            app.frame_idx_cur = 1;
            app.roi_cur.xy = [1, 1; ...
                app.mov_raw.MetaData.width, app.mov_raw.MetaData.height];
            app.roi_cur.f = 1:app.mov_raw.MetaData.frames;
            app.roi_cur.z = 1:app.mov_raw.MetaData.slices;
            app.reg_frames = 1:app.mov_raw.MetaData.frames;
            app.scalebar.scale.xr = opts.xRes;
            app.scalebar.scale.yr = opts.yRes;
            app.scalebar.bar.xlen = min(app.scalebar.bar.xlen, ...
                                        0.5*opts.width*opts.xRes);
            app.scalebar.bar.ylen = min(app.scalebar.bar.ylen, ...
                                        0.5*opts.height*opts.yRes);
            app.scalebar.bar.sy = opts.height - app.scalebar.bar.sx;
            app.scalebar.sf = min(get(0).ScreenSize(3:4)./[opts.width, opts.height]);
            app.fps.base = opts.frames/(rt(end)-rt(1)); % real time fps
            app.fps.fold = 1;                           % play speed fold
            % update player timer
            UpdatePlayerTimer(app, opts.frames, app.fps.base);
            app.viewer_src = "raw";
            app.merge_mode = "none";

            PanelRecovered(app);

            status = 0;
        end

        function ChangeRunModal(app)
            % this function change the running modal by memmap_flag
            if app.memmap_flag == true
                % change to shared mode
                % load data into memory
                if ~isempty(app.mov_raw)
                    app.mov_raw.spread();
                end
                if ~isempty(app.mov_aligned)
                    app.mov_aligned.spread();
                end
            else
                % change to exclusive mode
                % save memory data to disk
                if ~isempty(app.mov_raw)
                    app.mov_raw.gather();
                end
                if ~isempty(app.mov_aligned)
                    app.mov_aligned.gather();
                end
            end
        end

        function UpdateCurrentSlice(app, mode, shift)
            arguments
                app
                mode (1,1) string {mustBeMember(mode, ["NOR", "CA"])} ...
                    = "NOR";        % "NOR" for normal view, "CA" for channel alignment view 
                shift (1,3) double = [0, 0, 0]; % dx, dy, da
            end
            % This function update the current slice: app.sliceimg_cur for
            % viewing, where the data type is m*n*3 true color uint16 image
            % if loading the data info
            if ~isempty(app.mov_raw)
                if mode == "NOR"
                    % slice_s for source, slice_a for aligned
                    % if not z-projection mode, viewing the layer at z,t
                    if app.zproj_mode == "none"
                        slice_s = app.mov_raw.Movie(:,:,app.channel_idx_cur,...
                            app.slice_idx_cur,app.frame_idx_cur);
                        slice_s = TransformBCG(slice_s, app.view_opts);
                        if ~isempty(app.mov_aligned)
                            slice_a = app.mov_aligned.Movie(:,:,app.channel_idx_cur,...
                                app.slice_idx_cur,app.frame_idx_cur);
                            slice_a = TransformBCG(slice_a, app.view_opts);
                        else
                            slice_a = [];
                        end
                    else
                        if ~isempty(app.zprojimg.raw)
                            slice_s = app.zprojimg.raw(:,:,...
                                app.channel_idx_cur,app.frame_idx_cur);
                            slice_s = TransformBCG(slice_s, app.view_opts);
                        else
                            slice_s = [];
                        end
                        if ~isempty(app.zprojimg.aligned)
                            slice_a = app.zprojimg.aligned(:,:,...
                                app.channel_idx_cur,app.frame_idx_cur);
                            slice_a = TransformBCG(slice_a, app.view_opts);
                        else
                            slice_a = [];
                        end
                    end

                    % if no data to view, just return
                    if isempty(slice_s) && isempty(slice_a)
                        return;
                    end

                    % view the slice at the time point
                    pseudo_color = app.channel_order(app.channel_idx_cur);
                    switch app.viewer_src
                        case "raw"
                            slice_cur = CompositeC(slice_s,...
                                slice_a,...
                                app.merge_mode,...
                                1,...
                                pseudo_color);
                        case "aligned"
                            slice_cur = CompositeC(slice_s,...
                                slice_a,...
                                app.merge_mode,...
                                2,...
                                pseudo_color);
                        otherwise
                    end
                elseif mode == "CA"
                    % slice_s for signal channel, slice_a for align channel
                    if app.zproj_mode == "none"
                        if ~isempty(app.mov_raw)
                            [~, cidx] = ismember(app.reg_opts.Chls, ...
                                app.mov_raw.MetaData.cOrder);
                            slice_s = app.mov_raw.Movie(:,:,cidx,...
                                app.slice_idx_cur,app.frame_idx_cur);
                            slice_s = TransformBCG(slice_s, app.view_opts);
                            [~, cidx] = ismember(app.reg_opts.Chla, ...
                                app.mov_raw.MetaData.cOrder);
                            slice_a = app.mov_raw.Movie(:,:,cidx,...
                                app.slice_idx_cur,app.frame_idx_cur);
                            slice_a = TransformBCG(slice_a, app.view_opts);
                        end
                    else
                        if ~isempty(app.zprojimg.raw)
                            [~, cidx] = ismember(app.reg_opts.Chls, ...
                                app.mov_raw.MetaData.cOrder);
                            slice_s = app.zprojimg.raw(:,:,...
                                cidx,app.frame_idx_cur);
                            slice_s = TransformBCG(slice_s, app.view_opts);
                            [~, cidx] = ismember(app.reg_opts.Chla, ...
                                app.mov_raw.MetaData.cOrder);
                            slice_a = app.zprojimg.raw(:,:,...
                                cidx,app.frame_idx_cur);
                            slice_a = TransformBCG(slice_a, app.view_opts);
                        end
                    end
                    % do imwarp for slice_s motion
                    rot = [cosd(shift(3)), sind(shift(3)); ...
                           -sind(shift(3)), cosd(shift(3))];
                    RA = imref2d(size(slice_s), app.mov_raw.MetaData.xRes, ...
                        app.mov_raw.MetaData.yRes);
                    tform = rigid2d(rot, shift(1:2));
                    slice_s_tf = imwarp(slice_s, RA, tform, "linear", ...
                        "FillValues",prctile(slice_s, 5, "all"), ...
                        "OutputView", RA);
                    slice_cur = CompositeC(slice_s_tf,...
                                           slice_a,...
                                           "none",...
                                           1,...
                                           "mix");
                end

                app.sliceimg_cur = slice_cur;
            end
        end

        function SourceZProjection(app, frame, method)
            % default z projection is max z projection
            if ~exist("method","var") || isempty(method)
                app.mov_raw.ZProjMethod = "max";
            else
                if method == "none"
                    % no update and quit immediately
                    return;
                else
                    app.mov_raw.ZProjMethod = method;
                end
            end

            % no matter what, the raw source must be projected at z direction
            if ~exist("frame","var") || isempty(frame)
                app.zprojimg.raw = app.mov_raw.MovieZProj;
            else
                % increasing update
                app.zprojimg.raw(:,:,:,frame) = ...
                    app.mov_raw.crop("T", [frame, frame]).MovieZProj;
            end

            % and if there we have aligned source, we need to synchronize 
            % the two sources because that the merge mode is valid
            if isequal(app.DropDown_Source.Items, ...
                    [app.region_info.mapping.RAW, app.region_info.mapping.ALIGNED])
                app.mov_aligned.ZProjMethod = app.mov_raw.ZProjMethod;
                if ~exist("frame","var") || isempty(frame)
                    app.zprojimg.aligned = app.mov_aligned.MovieZProj;
                else
                    % increasing update
                    app.zprojimg.aligned(:,:,:,frame) = ...
                        app.mov_aligned.crop("T", [frame, frame]).MovieZProj;
                end
            end
        end

        function SourceProjectionAtT(app)
            switch app.viewer_src
                case "raw"
                    switch app.template_sync
                        case "global"
                            app.tprojimg.raw = Projection(...
                                app.mov_raw.Movie,...
                                app.reg_opts.volfixed.G(1),5,...
                                app.reg_opts.volfixed.G(2));
                        case "local"
                            app.tprojimg.raw = Projection(...
                                app.mov_raw.Movie,...
                                app.reg_opts.volfixed.L(1),5,...
                                app.reg_opts.volfixed.L(2));
                        otherwise
                    end
                case "aligned"
                    switch app.template_sync
                        case "global"
                            app.tprojimg.aligned = Projection(...
                                app.mov_aligned.Movie,...
                                app.reg_opts.volfixed.G(1),5,...
                                app.reg_opts.volfixed.G(2));
                        case "local"
                            app.tprojimg.aligned = Projection(...
                                app.mov_aligned.Movie,...
                                app.reg_opts.volfixed.L(1),5,...
                                app.reg_opts.volfixed.L(2));
                        otherwise
                    end
                otherwise
            end
        end

        function status = RemoveCameraBKG(app, border_px)
            arguments
                app
                border_px (1,1) double {mustBeNonnegative, mustBeInteger} = 0;
            end
            % Note that: 95B on CSU-X1 noise ~ N(100, 2^2)
            status = 0;
            if app.Menu_RemoveBackgroundFix.Checked == "on"
                switch numel(app.mov_raw.MetaData.cOrder)
                    case 1
                        % single channel case
                        prompt = {['\fontsize{12} ', app.region_info.mapping.FUNCTIONAL_BACKGROUND.char()]};
                        dlgtitle = app.region_info.mapping.REMOVE_BACKGROUND.char();
                        dims = [1 40];
                        definput = {'100'};
                        dlgopts.WindowStyle = 'normal';
                        dlgopts.Interpreter = "tex";
                        dlgout = inputdlg(prompt,dlgtitle,dims,definput,dlgopts);
                        if isempty(dlgout)
                            status = -1;
                            return;
                        else
                            dlgout=dlgout{1};
                        end
                        mov_bkg = str2double(dlgout);

                        if isnan(mov_bkg) || mov_bkg < 0
                            uialert(app.Reg3DV200WukongUIFigure, ...
                                app.region_info.mapping.INVALID_BACKGROUND, ...
                                app.region_info.mapping.ERROR, ...
                                "Icon","error");
                            status = -1;
                            return;
                        end

                        if mov_bkg > min(app.mov_raw.Movie, [], "all")
                            slt = uiconfirm(app.Reg3DV200WukongUIFigure, ...
                                app.region_info.mapping.IRRECOVERABLE_BACKGROUND, ...
                                app.region_info.mapping.WARNING, ...
                                "Icon","warning");
                            if slt == "OK"
                                app.mov_raw.Movie = app.mov_raw.Movie - mov_bkg;
                                % note that geometric transformation does
                                % not change the background estimation, so
                                % that 'raw' and 'aligned' share the same
                                % value
                                if ~isempty(app.mov_aligned)
                                    % synchronize with aligned source
                                    app.mov_aligned.Movie = app.mov_aligned.Movie - mov_bkg;
                                end
                            else
                                status = -1;
                                return;
                            end
                        else
                            app.mov_raw.Movie = app.mov_raw.Movie - mov_bkg;
                            if ~isempty(app.mov_aligned)
                                % synchronize with aligned source
                                app.mov_aligned.Movie = app.mov_aligned.Movie - mov_bkg;
                            end
                        end
                    case 2
                        [~, sc] = ismember(app.reg_opts.Chla, app.mov_raw.MetaData.cOrder);
                        [~, fc] = setdiff(1:2, sc);
                        % dual channels
                        prompt = {['\fontsize{12} ', app.region_info.mapping.STRUCTURED_BACKGROUND.char()], ...
                            ['\fontsize{12} ', app.region_info.mapping.FUNCTIONAL_BACKGROUND.char()]};
                        dlgtitle = app.region_info.mapping.REMOVE_BACKGROUND.char();
                        dims = [1 40;1 40];
                        definput = {'100', '100'};
                        dlgopts.WindowStyle = 'normal';
                        dlgopts.Interpreter = "tex";
                        dlgout = inputdlg(prompt,dlgtitle,dims,definput,dlgopts);
                        if isempty(dlgout)
                            status = -1;
                            return;
                        end
                        mov_bkg = str2double(dlgout);

                        if any(isnan(mov_bkg)) || any(mov_bkg < 0)
                            uialert(app.Reg3DV200WukongUIFigure, ...
                                app.region_info.mapping.INVALID_BACKGROUND, ...
                                app.region_info.mapping.ERROR, ...
                                "Icon","error");
                            status = -1;
                            return;
                        end

                        if mov_bkg(1) > min(app.mov_raw.Movie(:,:,sc,:,:),[],"all") ...
                                || mov_bkg(2) > min(app.mov_raw.Movie(:,:,fc,:,:),[],"all")
                            slt = uiconfirm(app.Reg3DV200WukongUIFigure, ...
                                app.region_info.mapping.IRRECOVERABLE_BACKGROUND, ...
                                app.region_info.mapping.WARNING, ...
                                "Icon","warning");
                            if slt == "OK"
                                % apply the intensity shift
                                app.mov_raw.Movie(:,:,sc,:,:) = ...
                                    app.mov_raw.Movie(:,:,sc,:,:) - mov_bkg(1);
                                app.mov_raw.Movie(:,:,fc,:,:) = ...
                                    app.mov_raw.Movie(:,:,fc,:,:) - mov_bkg(2);
                                if ~isempty(app.mov_aligned)
                                    % synchronize with aligned source
                                    app.mov_aligned.Movie(:,:,sc,:,:) = ...
                                        app.mov_aligned.Movie(:,:,sc,:,:) - mov_bkg(1);
                                    app.mov_aligned.Movie(:,:,fc,:,:) = ...
                                        app.mov_aligned.Movie(:,:,fc,:,:) - mov_bkg(2);
                                end
                            else
                                status = -1;
                                return;
                            end
                        else
                            % apply the intensity shift
                            app.mov_raw.Movie(:,:,sc,:,:) = ...
                                app.mov_raw.Movie(:,:,sc,:,:) - mov_bkg(1);
                            app.mov_raw.Movie(:,:,fc,:,:) = ...
                                app.mov_raw.Movie(:,:,fc,:,:) - mov_bkg(2);
                            if ~isempty(app.mov_aligned)
                                % synchronize with aligned source
                                app.mov_aligned.Movie(:,:,sc,:,:) = ...
                                    app.mov_aligned.Movie(:,:,sc,:,:) - mov_bkg(1);
                                app.mov_aligned.Movie(:,:,fc,:,:) = ...
                                    app.mov_aligned.Movie(:,:,fc,:,:) - mov_bkg(2);
                            end
                        end
                    otherwise
                end
                    
            elseif app.Menu_RemoveBackgroundAuto.Checked == "on"
                % using the minimum value of train as the background
                % estimation
                bar = uiprogressdlg(app.Reg3DV200WukongUIFigure,...
                    "Icon","info", "Indeterminate","on",...
                    "Message",app.region_info.mapping.REMOVING_BACKGROUND);
                switch numel(app.mov_raw.MetaData.cOrder)
                    case 1
                        if border_px == 0
                            mov_bkg = median(app.mov_raw.Movie,"all");
                        else
                            border = app.mov_raw.Movie(...
                                [1:border_px, end-border_px+1:end], ...
                                [1:border_px, end-border_px+1:end], ...
                                :,:);
                            mov_bkg = median(border, "all");
                        end
                        app.mov_raw.Movie = app.mov_raw.Movie - mov_bkg;
                        if ~isempty(app.mov_aligned)
                            % synchronize with aligned source
                            app.mov_aligned.Movie = app.mov_aligned.Movie - mov_bkg;
                        end
                    case 2
                        [~, sc] = ismember(app.reg_opts.Chla, app.mov_raw.MetaData.cOrder);
                        [~, fc] = setdiff(1:2, sc);
                        if border_px == 0
                            mov_bkg(1) = min(app.mov_raw.Movie(:,:,sc,:,:), [], "all");
                            mov_bkg(2) = min(app.mov_raw.Movie(:,:,fc,:,:), [], "all");
                        else
                            border = app.mov_raw.Movie(...
                                [1:border_px, end-border_px+1:end], ...
                                [1:border_px, end-border_px+1:end], ...
                                sc,:,:);
                            mov_bkg(1) = median(border, "all");
                            border = app.mov_raw.Movie(...
                                [1:border_px, end-border_px+1:end], ...
                                [1:border_px, end-border_px+1:end], ...
                                fc,:,:);
                            mov_bkg(2) = median(border, "all");
                        end
                        app.mov_raw.Movie(:,:,sc,:,:) = ...
                            app.mov_raw.Movie(:,:,sc,:,:) - mov_bkg(1);
                        app.mov_raw.Movie(:,:,fc,:,:) = ...
                            app.mov_raw.Movie(:,:,fc,:,:) - mov_bkg(2);
                        if ~isempty(app.mov_aligned)
                            % synchronize with aligned source
                            app.mov_aligned.Movie(:,:,sc,:,:) = ...
                                app.mov_aligned.Movie(:,:,sc,:,:) - mov_bkg(1);
                            app.mov_aligned.Movie(:,:,fc,:,:) = ...
                                app.mov_aligned.Movie(:,:,fc,:,:) - mov_bkg(2);
                        end
                    otherwise
                end
            end

            close(bar);

            app.mov_raw.Background = mov_bkg;
            if ~isempty(app.mov_aligned)
                app.mov_aligned.Background = mov_bkg;
            end
            app.bkgextract_flag = true;

            GenSysMsg(app, ["REMOVED_BACKGROUND",""]);
        end

        function RecoverCameraBKG(app)
            % recover the background
            if numel(app.mov_raw.MetaData.cOrder) == 1
                app.mov_raw.Movie = ...
                    app.mov_raw.Movie + app.mov_raw.Background;
                if ~isempty(app.mov_aligned)
                    % synchronize with aligned source
                    app.mov_aligned.Movie = ...
                        app.mov_aligned.Movie + app.mov_aligned.Background;
                end
            elseif numel(app.mov_raw.MetaData.cOrder) == 2
                [~, sc] = ismember(app.reg_opts.Chla, app.mov_raw.MetaData.cOrder);
                [~, fc] = setdiff(1:2, sc);
                app.mov_raw.Movie(:,:,sc,:,:) = ...
                    app.mov_raw.Movie(:,:,sc,:,:) + app.mov_raw.Background(1);
                app.mov_raw.Movie(:,:,fc,:,:) = ...
                    app.mov_raw.Movie(:,:,fc,:,:) + app.mov_raw.Background(2);
                if ~isempty(app.mov_aligned)
                    % synchronize with aligned source
                    app.mov_aligned.Movie(:,:,sc,:,:) = ...
                        app.mov_aligned.Movie(:,:,sc,:,:) + app.mov_aligned.Background(1);
                    app.mov_aligned.Movie(:,:,fc,:,:) = ...
                        app.mov_aligned.Movie(:,:,fc,:,:) + app.mov_aligned.Background(2);
                end
            end

            app.mov_raw.Background = 0*app.mov_raw.Background;
            if ~isempty(app.mov_aligned)
                % synchronize with aligned source
                app.mov_aligned.Background = 0*app.mov_aligned.Background;
            end
            app.bkgextract_flag = false;

            GenSysMsg(app, ["RECOVERED_BACKGROUND",""]);
        end

        function DrawScaleBar(app)
            scale = app.scalebar.scale;
            bar = app.scalebar.bar;

            % remove the exist scale bar
            if ~isempty(app.scalebar.handle_x) && isvalid(app.scalebar.handle_x)
                delete(app.scalebar.handle_x);
                app.scalebar.handle_x = [];
            end
            if ~isempty(app.scalebar.handle_y) && isvalid(app.scalebar.handle_y)
                delete(app.scalebar.handle_y);
                app.scalebar.handle_y = [];
            end
            if ~isempty(app.scalebar.txtobj_x) && isvalid(app.scalebar.txtobj_x)
                delete(app.scalebar.txtobj_x);
                app.scalebar.txtobj_x = [];
            end
            if ~isempty(app.scalebar.txtobj_y) && isvalid(app.scalebar.txtobj_y)
                delete(app.scalebar.txtobj_y);
                app.scalebar.txtobj_y = [];
            end
            if ((bar.xlen/scale.xr >= app.mov_raw.MetaData.width - 20) && bar.ishor)...
                    || ((bar.ylen/scale.yr >= app.mov_raw.MetaData.height - 20) && bar.isver)
                uialert(app.Reg3DV200WukongUIFigure, ...
                    app.region_info.mapping.INVALID_RULER, ...
                    app.region_info.ERROR, "Icon","error");
                return;
            end

            % update the selected bar
            if bar.mk_fill == true
                fc = bar.color;
            else
                fc = "none";
            end

            if bar.ishor == true
                switch bar.mk
                    case "flat"
                        ept = "|";
                    otherwise
                        ept = bar.mk;
                end
                
                % generate the horizontal scale bar
                app.scalebar.handle_x = line(app.UIAxes_Slice, ...
                    [bar.sx, bar.sx+bar.xlen/scale.xr], ...
                    [bar.sy, bar.sy], ...
                    "Color", bar.color,"LineWidth",bar.linw,"Marker",ept,...
                    "MarkerSize",bar.mk_size,"MarkerFaceColor",fc);
                % bunding text on scale bar
                app.scalebar.txtobj_x = text(app.UIAxes_Slice,...
                    bar.sx+bar.xlen/scale.xr/2,...
                    bar.sy+app.scalebar.sf*bar.txt_space,...
                    string(bar.xlen)+" "+scale.unit,'Color',bar.color,'FontSize',bar.font.FontSize,...
                    'FontWeight',bar.font.FontWeight,'FontName',bar.font.FontName,...
                    'FontAngle',bar.font.FontAngle,'FontUnits',bar.font.FontUnits,...
                    'HorizontalAlignment','center');
                % add self define callback
                app.scalebar.handle_x.ButtonDownFcn = ...
                    createCallbackFcn(app,@ScaleBar_ButtonDownFcn,true);
            end

            if bar.isver == true
                switch bar.mk
                    case "flat"
                        ept = "_";
                    otherwise
                        ept = bar.mk;
                end
                % generate the vertical scale bar
                app.scalebar.handle_y = line(app.UIAxes_Slice, ...
                    [bar.sx, bar.sx]+bar.xlen/scale.xr, ...
                    [bar.sy, bar.sy-bar.ylen/scale.yr], ...
                    "Color", bar.color,"LineWidth",bar.linw,"Marker",ept,...
                    "MarkerSize",bar.mk_size,"MarkerFaceColor",fc);
                % bunding text on scale bar
                app.scalebar.txtobj_y = text(app.UIAxes_Slice,...
                    bar.sx+bar.xlen/scale.xr+app.scalebar.sf*bar.txt_space,...
                    bar.sy-bar.ylen/scale.yr/2,...
                    string(bar.ylen)+" "+scale.unit,'Color',bar.color,'FontSize',bar.font.FontSize,...
                    'FontWeight',bar.font.FontWeight,'FontName',bar.font.FontName,...
                    'FontAngle',bar.font.FontAngle,'FontUnits',bar.font.FontUnits,...
                    'Rotation',90,'HorizontalAlignment','center');
                % add self define callback
                app.scalebar.handle_y.ButtonDownFcn = ...
                    createCallbackFcn(app,@ScaleBar_ButtonDownFcn,true);
            end

            % initialize the motion status
            app.scalebar.ismoving = false;
        end

        function status = UpdateRegion(app, lang)
            if ismember(lang, ["zh_CN","en_US","ru_RU","fr_FR","es_ES"])
                app.region_info.lang = lang;
                if ispc()
                    app.region_info.mapping = readstruct("lang\"...
                        +lang+".xml","FileType","xml");
                elseif isunix()
                    app.region_info.mapping = readstruct("lang/"...
                        +lang+".xml","FileType","xml");
                else
                    % use default text
                    warning("Unsupported platform.");
                    return;
                end

                status = 0;
            else
                status = -1;
            end
        end
        
        %================= USER INTERFSACE OPTIMIZATION ==================
        
        function PanelWaiting(app)
            % set the pointer for better visual effort
            set(app.Reg3DV200WukongUIFigure,'Pointer','watch');
            drawnow
        end

        function PanelRecovered(app)
            % set the pointer back
            set(app.Reg3DV200WukongUIFigure,'Pointer','arrow');
            drawnow
        end

        %==================== SYSTEM PRESET SET/GET ======================
       
        function SavePreset(app,filename)
            % generate a struct contain part of stable variables as preset
            preset_save = app.reg_opts;
            preset_save.fps_fold = app.fps.fold;
            preset_save.key_mode = app.key_mode;
            preset_save.tpl_sync = app.template_sync;
            writestruct(preset_save,filename,"FileType","xml");
            GenSysMsg(app, ["CONFIGURATION_EXPORTED",""]);
        end

        function preset_load = LoadPreset(app, filename)
            preset_load = readstruct(filename,"FileType","xml");

            % check the fields
            fields_name = fieldnames(preset_load);
            reg_fieldsname = fieldnames(app.reg_opts);
            fields_apdx = {'fps_fold';'key_mode';'tpl_sync'};
            if all(ismember(fields_name,union(reg_fieldsname,fields_apdx)))...
                    && numel(fields_name) == numel(reg_fieldsname) + numel(fields_apdx)
                for k = 1:numel(reg_fieldsname)
                    app.reg_opts.(reg_fieldsname{k}) = ...
                        preset_load.(reg_fieldsname{k});
                end
                app.fps.fold = preset_load.fps_fold;
                app.key_mode = preset_load.key_mode;
                app.template_sync = preset_load.tpl_sync;
                GenSysMsg(app, ["CONFGURATION_LOADED",""]);
            else
                uialert(app.Reg3DV200WukongUIFigure, ...
                    app.region_info.CONFIGURATION_CORRUPTED,...
                    app.region_info.ERROR, "Icon","error");
                preset_load = [];
                return;
            end
        end

        %===================== SYSTEM CONTROLLER =========================

        function UpdateSystemAfterLoading(app)
            InitRegOptions(app);

            InitPanels(app);

            AppendColorChannelMenu(app);

            % Unlock the options
            app.Menu_SaveImageAs.Enable = "on";
            app.Menu_SaveTimeAs.Enable = "on";
            app.Menu_SaveMovieAs.Enable = "on";
            app.Menu_Image.Enable = "on";
            if app.mov_raw.MetaData.channels == 1
                app.Menu_ChannelAlignment.Enable = "off";
            elseif app.mov_raw.MetaData.channels == 2
                app.Menu_ChannelAlignment.Enable = "on";
            end
            app.Menu_Animation.Enable = "on";
            app.Menu_Horizontal.Enable = "on";
            app.Menu_Vertical.Enable = "on";
            app.Menu_ROI.Enable = "on";
            app.Menu_Channel.Enable = "on";
            app.Menu_TimeStamp.Checked = "off";
            app.Menu_MetaMarker.Checked = "off";
            app.Menu_Register.Enable = "on";
            app.Menu_KeyMode.Enable = "on";
            app.Menu_RegOptions.Enable = "on";
            app.Menu_RegRange.Enable = "on";
            app.DropDown_Source.Enable = "on";
            app.DropDown_Source.Items = app.region_info.mapping.RAW;
            app.Button_Run.Enable = "on";

            app.is_data_loaded = true;

            InitUIAxes_Slice(app);

            RefreshViewer(app);

            SetSlider_Slice(app,'on');
            SetSlider_Time(app,'on',[1, app.mov_raw.MetaData.frames]);
        end

        function RegController(app, obj, event)
            % This callback will be running forever when the app is running
            obj; %#ok<VUNUS> 
            event; %#ok<VUNUS> 

            switch app.reg_require
                case "on"
                    % the upbound side
                    app.reg_require = "none";   % none for running

                    % run
                    app.Button_Run.BackgroundColor = [0.4,1,0];
                    app.Button_Run.Text = app.region_info.mapping.IN_PROGRESS;

                    status = RunRegistration(app);

                    % post setting
                    if status ~= 0
                        % registration start failed
                        app.reg_require = "off";    % for auto closing when next asking
                        return;
                    end

                    % append the merge menu
                    if isempty(app.Menu_MergeSource) ...
                            || ~isvalid(app.Menu_MergeSource)
                        AppendMergeChannelMenu(app);
                    end

                    app.img_operators = setdiff(union(app.img_operators, "aligned"), "none");
                    % foolproof point
                    app.DropDown_Source.Items = [app.region_info.mapping.RAW, ...
                                                 app.region_info.mapping.ALIGNED];
                    app.DropDown_Source.Value = "ALIGNED";
                    app.viewer_src = "aligned";
                    if app.reg_opts.RegType == "manual"
                        SourceZProjection(app, app.frame_idx_cur);
                    else
                        SourceZProjection(app);
                    end

                    RefreshViewer(app);

                    % stop the system
                    app.Button_Run.Value = false;
                    app.Button_Run.Text = app.region_info.mapping.RUN;
                    app.Button_Run.BackgroundColor = [0.96,0.96,0.96];
                    app.Menu_SaveGeoTformAs.Enable = "on";

                    % unlock options
                    app.Menu_RegEvaluate.Enable = "on";
                    app.Menu_RegHistory.Enable = "on";
                    UnlockComponentsAfterRegistration(app);
                    if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                        app.RS_handle.UnlockPanel();
                    end
                case "off"
                    % the downbound side
                    app.reg_require = "none";   % none for sleeping
                    StopRegistration(app);

                    % stop the system
                    app.Button_Run.Value = false;
                    app.Button_Run.Text = app.region_info.mapping.RUN;
                    app.Button_Run.BackgroundColor = [0.96,0.96,0.96];

                    % unlock options
                    UnlockComponentsAfterRegistration(app);
                    if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                        app.RS_handle.UnlockPanel();
                    end
                case "none"
                    % % Yeah, poor checker can go to sleep
                    % stop(app.checker_timer);
                otherwise
                    % none 
            end

            % Yeah, poor checker can go to sleep
            stop(app.checker_timer);
        end

        function LockComponentsBeforeRegistration(app)
            app.Menu_File.Enable = "off";
            app.Menu_Image.Enable = "off";
            app.Menu_Options.Enable = "off";
            app.Menu_Help.Enable = "off";
            app.Spinner_Slice.Enable = "off";
            app.Slider_Slice.Enable = "off";
            app.Spinner_Time.Enable = "off";
            app.Slider_Time.Enable = "off";
            app.DropDown_Source.Enable = "off";
            app.Menu_Horizontal.Enable = "off";
            app.Menu_Vertical.Enable = "off";
            app.Menu_RectangleROI.Enable = "off";
            app.Menu_CircleROI.Enable = "off";
            app.Menu_PolygonROI.Enable = "off";
            app.Menu_FreehandROI.Enable = "off";
            app.Menu_ExportLog.Enable = "off";
            app.Menu_ClearLog.Enable = "off";
            app.TextArea_Log.Enable = "off";
            app.Switch_Log.Enable = "off";
        end

        function UnlockComponentsAfterRegistration(app)
            app.Menu_File.Enable = "on";
            app.Menu_Image.Enable = "on";
            app.Menu_Options.Enable = "on";
            app.Menu_Help.Enable = "on";
            if app.zproj_mode == "none"
                app.Spinner_Slice.Enable = "on";
                app.Slider_Slice.Enable = "on";
            end
            app.Spinner_Time.Enable = "on";
            app.Slider_Time.Enable = "on";
            app.DropDown_Source.Enable = "on";
            app.Menu_Horizontal.Enable = "on";
            app.Menu_Vertical.Enable = "on";
            app.Menu_RectangleROI.Enable = "on";
            app.Menu_CircleROI.Enable = "on";
            app.Menu_PolygonROI.Enable = "on";
            app.Menu_FreehandROI.Enable = "on";
            app.Menu_ExportLog.Enable = "on";
            app.Menu_ClearLog.Enable = "on";
            app.TextArea_Log.Enable = "on";
            app.Switch_Log.Enable = "on";
        end

        function status = RunRegistration(app)
            switch app.reg_opts.RegType
                case "auto"
                    % Check the system status first
                    switch app.GetSystemState()
                        case app.REGMODE_NOT_READY
                            %check twinkling
                            if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                                app.RS_handle.FontBlink(["CheckBox_GlobalOn","CheckBox_LocalOn"]);
                            end
                            GenSysMsg(app, ["REGISTRATION_WORKFLOW_ERROR",""]);
                            status = -1;
                            return;
                        case app.SOURCE_NOT_READY
                            %
                        otherwise
                    end

                    if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                        app.RS_handle.LockPanel();
                    end
                    LockComponentsBeforeRegistration(app);

                    GenSysMsg(app,["PERFORM_AUTO_REGISTRATION",""]);

                    % preprocess the gui interaction
                    drawnow nocallbacks;
                    pause(0.5);

                    % we using different method for one channel and two 
                    % channels motion correction
                    if numel(app.channel_order) == 1
                        % one channel registration
                        status = RunAutoRegistration_OC(app);
                    elseif numel(app.channel_order) == 2
                        % two channels registration
                        status = RunAutoRegistration_TC(app);
                    else
                        status = -1;
                        return;
                    end

                case "manual"
                    % app.Button_Run.BackgroundColor = [0.4,1,0];

                    if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                        app.RS_handle.LockPanel();
                    end
                    LockComponentsBeforeRegistration(app);

                    reg_params = {...
                        'tform',app.reg_opts.ManualTform,...
                        'degree',app.reg_opts.Degree,...
                        'nlwm',app.reg_opts.Nlwm,...
                        'loc',app.channel_idx_cur,...
                        'interp',app.reg_opts.ManualInterp,...
                        'smooth',app.reg_opts.ManualEdgeSmooth...
                        };

                    switch app.viewer_src
                        case "raw"
                            SourceProjectionAtT(app);
                            % if there exist aligned movie, do
                            % registration on raw means we need redo
                            % the registration to replace the current
                            % result, so that we copy the source movie
                            % only at first time
                            if isempty(app.mov_aligned)
                                app.mov_aligned = app.mov_raw.copy();   % deep copy 
                            end

                            % do manual registration and replace the
                            % auto registration results
                            [app.mov_aligned.Movie(:,:,:,:,app.frame_idx_cur),...
                                app.mov_aligned.Transformation{app.frame_idx_cur,3}] ...
                                = reg_manual(app.tprojimg.raw,...
                                app.mov_raw.Movie(:,:,:,:,app.frame_idx_cur),...
                                reg_params{:});

                            % because we do the manual registration on raw
                            % movie, that means the auto registration
                            % result will be covered
                            app.mov_aligned.Transformation(app.frame_idx_cur,1:2) = {[], []};

                        case "aligned"
                            SourceProjectionAtT(app);

                            [app.mov_aligned.Movie(:,:,:,:,app.frame_idx_cur),...
                                app.mov_aligned.Transformation{app.frame_idx_cur,3}] ...
                                = reg_manual(app.tprojimg.aligned,...
                                app.mov_aligned.Movie(:,:,:,:,app.frame_idx_cur),...
                                reg_params{:});
                        otherwise
                    end

                    t_str = sprintf(" %.1f",app.mov_aligned.Time(app.frame_idx_cur));
                    GenSysMsg(app, ["REGISTRATION_FRAMES", t_str]);

                    app.Menu_RegEvaluate.Enable = "on";
                    app.Menu_RegHistory.Enable = "on";
                    UnlockComponentsAfterRegistration(app);
                    if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                        app.RS_handle.UnlockPanel();
                    end

                    status = 0;
                case "longterm"
                    if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                        app.RS_handle.LockPanel();
                    end
                    LockComponentsBeforeRegistration(app);

                    GenSysMsg(app,["PERFORM_LONGTERM_REGISTRATION",""]);

                    % preprocess the gui interaction
                    drawnow nocallbacks;
                    pause(0.5);

                    status = RunAutoRegistration_LCTC(app);
                otherwise
            end
        end

        % This function runs one channel registration
        function status = RunAutoRegistration_OC(app)
            % adjust if the file is big enough
            processing_ratio = numel(app.reg_frames)...
                /app.mov_raw.MetaData.frames;
            if IsBigFile(app.mov_raw.Bytes.mem, processing_ratio, 25)
                bigfile_flag = "on";
            else
                bigfile_flag = "off";
            end

            % extract the registration parameters
            reg_params = {...
                'RefVol',app.reg_opts.volfixed,...
                'RegMode',app.reg_opts.RegMode,...
                'RegFrames',app.reg_frames,...
                'Modal',app.reg_opts.RegModal,...
                'Tform',app.reg_opts.AutoTform,...
                'RL',app.reg_opts.RL,...
                'GA',app.reg_opts.GA,...
                'InsTh',app.reg_opts.InsTh,...
                'ScaleTh',app.reg_opts.ScaleTh,...
                'GridUnit',app.reg_opts.GridUnit,...
                'InitStep',app.reg_opts.InitStep,...
                'MinStep',app.reg_opts.MinStep,...
                'MaxIterN_Rigid',app.reg_opts.MaxIterN_Rigid,...
                'MaxIterN_NonRigid',app.reg_opts.MaxIterN_NonRigid,...
                'IterCoeff',app.reg_opts.IterCoeff,...
                'AFS',app.reg_opts.AFS,...
                'VPL_Rigid',app.reg_opts.VPL_Rigid,...
                'VPL_NonRigid',app.reg_opts.VPL_NonRigid,...
                'Interp_Rigid',app.reg_opts.Interp_Rigid,...
                'Interp_NonRigid',app.reg_opts.Interp_NonRigid,...
                'BigFile',bigfile_flag...
                };

            reg_frupd = app.reg_frames;

            switch app.viewer_src
                case "raw"
                    tic;
                    if isempty(app.mov_aligned)
                        % copy the raw movie and clear all results
                        % as initialized
                        app.mov_aligned = app.mov_raw.copy();
                    else
                        % re run the auto registration for bad time
                        % series segmentation
                    end

                    if ismember('BigFile',reg_params(1:2:end)) &&...
                            struct(reg_params{:}).BigFile == "on"
                        [signal_ptr, app.mov_aligned.Transformation(reg_frupd,1:2)]...
                            = reg_oc_auto(app.mov_raw,[],reg_params{:});
                        % load the data into memory
                        app.mov_aligned.Movie(:,:,1,:,reg_frupd)...
                            = signal_ptr.mov_signal;
                    else
                        [app.mov_aligned.Movie(:,:,1,:,reg_frupd), ...
                            app.mov_aligned.Transformation(reg_frupd,1:2)] ...
                            = reg_oc_auto(app.mov_raw,[],reg_params{:});
                    end

                    reg_time = toc;
                case "aligned"
                    selection = uiconfirm(app.Reg3DV200WukongUIFigure,...
                        app.region_info.mapping.COVERAGE_OPERATION,...
                        app.region_info.mapping.WARNING, "Icon","warning");

                    if selection == "OK"
                        tic;
                        if ismember('BigFile',reg_params(1:2:end)) &&...
                                struct(reg_params{:}).BigFile == "on"
                            % load the data into memory
                            [signal_ptr, app.mov_aligned.Transformation(reg_frupd,1:2)]...
                                = reg_oc_auto(app.mov_aligned.Movie,[],reg_params{:});
                            app.mov_aligned.Movie(:,:,1,:,reg_frupd) = signal_ptr.mov_signal;
                        else
                            [app.mov_aligned.Movie(:,:,1,:,reg_frupd), ...
                                app.mov_aligned.Transformation(reg_frupd,1:2)] ...
                                = reg_oc_auto(app.mov_aligned.Movie,[],reg_params{:});
                        end

                        reg_time = toc;
                    else
                        % stop the system
                        app.Button_Run.Value = false;
                        app.Button_Run.BackgroundColor = [0.96,0.96,0.96];
                        UnlockComponentsAfterRegistration(app);
                        if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                            app.RS_handle.UnlockPanel();
                        end
                        status = -1;
                        return;
                    end
                otherwise
            end
            app.Menu_Register.Enable = "on";

            GenSysMsg(app, ["REGISTRATION_COMPLETE",""]);
            GenSysMsg(app, ["TIME_COST", sprintf(" %.1f",reg_time)]);

            status = 0;

        end

        % This function runs two channels registration
        function status = RunAutoRegistration_TC(app)
            % transform to indices in channnel order
            [~, signal_c] = ismember(app.reg_opts.Chls, app.channel_order);
            [~, marker_c] = ismember(app.reg_opts.Chla, app.channel_order);

            % adjust if the file is big enough
            processing_ratio = numel(app.reg_frames)...
                /app.mov_raw.MetaData.frames;
            if IsBigFile(app.mov_raw.Bytes.mem, processing_ratio)
                bigfile_flag = "on";
            else
                bigfile_flag = "off";
            end

            % extract the registration parameters
            reg_params = {...
                'RefVol',app.reg_opts.volfixed,...
                'RegMode',app.reg_opts.RegMode,...
                'RegFrames',app.reg_frames,...
                'Modal',app.reg_opts.RegModal,...
                'Tform',app.reg_opts.AutoTform,...
                'AutoContrast',app.reg_opts.AutoContrast,...
                'CompAcc',app.reg_opts.CompAcc,...
                'InitStep',app.reg_opts.InitStep,...
                'MinStep',app.reg_opts.MinStep,...
                'MaxIterN_Rigid',app.reg_opts.MaxIterN_Rigid,...
                'MaxIterN_NonRigid',app.reg_opts.MaxIterN_NonRigid,...
                'IterCoeff',app.reg_opts.IterCoeff,...
                'AFS',app.reg_opts.AFS,...
                'CoRegC',app.reg_opts.CoRegC,...
                'DS',app.reg_opts.DS,...
                'Chla',app.reg_opts.Chla,...
                'Chls',app.reg_opts.Chls,...
                'VPL_Rigid',app.reg_opts.VPL_Rigid,...
                'VPL_NonRigid',app.reg_opts.VPL_NonRigid,...
                'Interp_Rigid',app.reg_opts.Interp_Rigid,...
                'Interp_NonRigid',app.reg_opts.Interp_NonRigid,...
                'BigFile',bigfile_flag...
                };

            reg_frupd = app.reg_frames;

            switch app.viewer_src
                case "raw"
                    tic;
                    if isempty(app.mov_aligned)
                        % copy the raw movie and clear all results
                        % as initialized
                        app.mov_aligned = app.mov_raw.copy();
                    else
                        % re run the auto registration for bad time
                        % series segmentation
                    end

                    if ismember('BigFile',reg_params(1:2:end)) &&...
                            struct(reg_params{:}).BigFile == "on"
                        [signal_ptr, app.mov_aligned.Transformation(reg_frupd,1:2), marker_ptr]...
                            = reg_tc_auto(app.mov_raw,[],reg_params{:});
                        % load the data into memory
                        app.mov_aligned.Movie(:,:,signal_c,:,reg_frupd)...
                            = signal_ptr.mov_signal;
                        app.mov_aligned.Movie(:,:,marker_c,:,reg_frupd)...
                            = marker_ptr.mov_aligned;
                    else
                        [app.mov_aligned.Movie(:,:,signal_c,:,reg_frupd), ...
                            app.mov_aligned.Transformation(reg_frupd,1:2), ...
                            app.mov_aligned.Movie(:,:,marker_c,:,reg_frupd)] ...
                            = reg_tc_auto(app.mov_raw,[],reg_params{:});
                    end

                    reg_time = toc;
                case "aligned"
                    selection = uiconfirm(app.Reg3DV200WukongUIFigure,...
                        app.region_info.mapping.COVERAGE_OPERATION,...
                        app.region_info.mapping.WARNING, "Icon","warning");

                    if selection == "OK"
                        tic;
                        if ismember('BigFile',reg_params(1:2:end)) &&...
                                struct(reg_params{:}).BigFile == "on"
                            % load the data into memory
                            [signal_ptr, app.mov_aligned.Transformation(reg_frupd,1:2), marker_ptr]...
                                = reg_tc_auto(app.mov_aligned,[],reg_params{:});
                            app.mov_aligned.Movie(:,:,signal_c,:,reg_frupd) = signal_ptr.mov_signal;
                            app.mov_aligned.Movie(:,:,marker_c,:,reg_frupd) = marker_ptr.mov_aligned;
                        else
                            [app.mov_aligned.Movie(:,:,signal_c,:,reg_frupd), ...
                                app.mov_aligned.Transformation(reg_frupd,1:2), ...
                                app.mov_aligned.Movie(:,:,marker_c,:,reg_frupd)] ...
                                = reg_tc_auto(app.mov_aligned,[],reg_params{:});
                        end

                        reg_time = toc;
                    else
                        % stop the system
                        app.Button_Run.Value = false;
                        app.Button_Run.BackgroundColor = [0.96,0.96,0.96];
                        UnlockComponentsAfterRegistration(app);
                        if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                            app.RS_handle.UnlockPanel();
                        end
                        status = -1;
                        return;
                    end
                otherwise
            end
            app.Menu_Register.Enable = "on";

            GenSysMsg(app, ["REGISTRATION_COMPLETE",""]);
            GenSysMsg(app, ["TIME_COST", sprintf(" %.1f",reg_time)]);

            status = 0;
        end

        function status = RunAutoRegistration_LCTC(app)
            % transform to indices in channnel order
            [~, signal_c] = ismember(app.reg_opts.Chls, app.channel_order);
            [~, marker_c] = ismember(app.reg_opts.Chla, app.channel_order);

            % extract the registration parameters
            reg_params = {...
                'RefVol',app.reg_opts.volfixed,...
                'RegFrames',app.reg_frames,...
                'KeyFrames',app.reg_opts.KeyFrames,...
                'IntensityThreshold',app.reg_opts.IntensityThreshold,...
                'ScaleThreshold',app.reg_opts.ScaleThreshold,...
                'DS_PointCloud',app.reg_opts.DS_PointCloud,...
                'DS_Voxel',app.reg_opts.DS_Voxel,...
                'DS_PointCloud_Param',app.reg_opts.DS_PointCloud_Param,...
                'OutlierRatio',app.reg_opts.OutlierRatio,...
                'MaxIterN_PointCloud',app.reg_opts.MaxIterN_PointCloud,...
                'ErrorLimit',app.reg_opts.ErrorLimit,...
                'InitStep',app.reg_opts.InitStep_LongTerm,...
                'MinStep',app.reg_opts.MinStep_LongTerm,...
                'MaxIterN_Voxel',app.reg_opts.MaxIterN_Voxel,...
                'IterCoeff',app.reg_opts.IterCoeff_LongTerm,...
                'AutoContrast',app.reg_opts.AutoContrast_LongTerm,...
                'CompAcc',app.reg_opts.CompAcc_LongTerm,...
                'AFS',app.reg_opts.AFS_LongTerm,...
                'Chla',app.reg_opts.Chla,...
                'Chls',app.reg_opts.Chls,...
                };

            reg_frupd = app.reg_frames;

            switch app.viewer_src
                case "raw"
                    tic;
                    if isempty(app.mov_aligned)
                        % copy the raw movie and clear all results
                        % as initialized
                        app.mov_aligned= app.mov_raw.copy();
                    end

                    [signal_ptr, app.mov_aligned.Transformation(reg_frupd,1:2), marker_ptr]...
                        = reg_lctc_auto(app.mov_raw,[],reg_params{:});

                    % load the data into memory
                    app.mov_aligned.Movie(:,:,signal_c,:,reg_frupd)...
                        = signal_ptr.mov_signal;
                    app.mov_aligned.Movie(:,:,marker_c,:,reg_frupd)...
                        = marker_ptr.mov_aligned;

                    reg_time = toc;
                case "aligned"
                    uialert(app.Reg3DV200WukongUIFigure, ...
                        app.region_info.mapping.LONG_TERM_ONCE_ONLY, ...
                        app.region_info.mapping.WARNING, ...
                        "Icon","warning");
                    reg_time = 0;
                otherwise
            end
            app.Menu_Register.Enable = "on";

            GenSysMsg(app, ["REGISTRATION_COMPLETE",""]);
            GenSysMsg(app, ["TIME_COST", sprintf(" %.1f",reg_time)]);

            status = 0;
        end

        function StopRegistration(app)
            % What can I only do is telling the truth
            GenSysMsg(app, ["TERMINATE_REGISTRATION",""]);
        end

        function KickChecker(app)
            stop(app.checker_timer);
            pause(0.5);
            start(app.checker_timer);
        end

        function RemoveTimer(app)
            if ~isempty(app.checker_timer)
                if isvalid(app.checker_timer)
                    stop(app.checker_timer);
                end
                delete(app.checker_timer);
            end
            if ~isempty(app.player_timer)
                if isvalid(app.player_timer)
                    stop(app.player_timer);
                end
                delete(app.player_timer);
            end
        end

        function RunUpdateChecking(app)
            % This function read this app version and acquire update
            ver_pattern = '[0-9][.][0-9][.][0-9]';
            ver = string(app.Reg3DV200WukongUIFigure.Name);
            ver = regexp(ver,ver_pattern,"match");
            [status, url] = acquire_update(app.update_opts.repository, ver);

            switch status
                case "UPDATABLE"
                    slt = uiconfirm(app.Reg3DV200WukongUIFigure,...
                        app.region_info.mapping.ASK_FOR_UPDATE, ...
                        app.region_info.mapping.INFORMATION, "Icon","info");
                    if slt == "OK"
                        [~, file, ext] = fileparts(url);
                        file = [file, ext];
                        bar = uiprogressdlg(app.Reg3DV200WukongUIFigure,...
                            "Icon","info", "Indeterminate","on",...
                            "Message",app.region_info.mapping.DOWNLOADING);
                        if ispc()
                            if ~exist("downloads\","dir")
                                mkdir("downloads\");
                            end
                            copyfile(url, "downloads\"+file);
                        elseif isunix()
                            if ~exist("downloads/","dir")
                                mkdir("downloads/");
                            end
                            copyfile(url, "downloads/"+file);
                        else
                            uialert(app.Reg3DV200WukongUIFigure,...
                                app.region_info.mapping.UNSUPPORTED_OS,...
                                app.region_info.mapping.ERROR, "Icon","error");
                            return;
                        end

                        close(bar);

                        % call outer script generator
                        if ispc()
                            [~, att] = fileattrib("downloads\"+file);
                        elseif isunix()
                            [~, att] = fileattrib("downloads/"+file);
                        end
                        
                        status = reg3d_update(string(att.Name), app.update_opts.auto_clean);

                        % set the update flag
                        app.update_opts.is_update = status;

                        if status == true
                            uialert(app.Reg3DV200WukongUIFigure,...
                                app.region_info.mapping.FINISH_UPDATE, ...
                                app.region_info.mapping.INFORMATION,"Icon","success");
                        else
                            uialert(app.Reg3DV200WukongUIFigure,...
                                app.region_info.mapping.UPDATE_SCRIPT_FAILED, ...
                                app.region_info.mapping.ERROR, "Icon","error");
                            if ispc()
                                delete("downloads\"+file);
                            elseif isunix()
                                delete("downloads/"+file);
                            end
                        end
                    end
                case "NO_UPDATE"
                    uialert(app.Reg3DV200WukongUIFigure,...
                        app.region_info.mapping.LATEST_VERSION, ...
                        app.region_info.mapping.INFORMATION, "Icon","info");
                case "ACCESS_FAILED"
                    uialert(app.Reg3DV200WukongUIFigure,...
                        app.region_info.mapping.UNABLE_ACCESS_SERVER, ...
                        app.region_info.mapping.ERROR, "Icon","error");
                    return;
                otherwise
            end
        end

        function ValidateUpdate(app)
            % This function read the update profile and set options
            if ispc()
                fid = fopen("reg3d_startup_plugins\update_setting.ini","r");
            elseif isunix()
                fid = fopen("reg3d_startup_plugins/update_setting.ini","r");
            else
                uialert(app.Reg3DV200WukongUIFigure,...
                    app.region_info.mapping.UNSUPPORTED_OS,...
                    app.region_info.mapping.ERROR, "Icon","error");
                return;
            end
            if fid == -1
                uialert(app.Reg3DV200WukongUIFigure,...
                    app.region_info.mapping.UNABLE_ACCESS_UPDATE_SETTING,...
                    app.region_info.mapping.WARNING, "Icon","warning");
                app.Menu_Update.Enable = "off";
                return;
            end
            setting_profile = textscan(fid, "%s",'Delimiter','\n');
            fclose(fid);
            setting_profile = string(setting_profile{1});

            for k = 1:numel(setting_profile)
                if setting_profile(k).replace(" ","") == "" ...
                        || iscomment(setting_profile(k))
                    % skip this line
                    continue;
                end
                key = setting_profile(k).extractBefore(" = ");

                switch upper(key)
                    case "AUTO_UPDATE"
                        value = lower(setting_profile(k).extractAfter(" = "));
                        if value == "0" || value == "off" || value == "false"
                            app.update_opts.method = "manual";
                        elseif value == "1" || value == "on" || value == "true"
                            app.update_opts.method = "auto";
                        else
                            warning(app.region_info.mapping.UNRECOGNIZED_UPDATE_IDENTIFIER);
                        end
                    case "REPOSITORY"
                        value = setting_profile(k).extractAfter(" = ");
                        if isfolder(value)
                            app.update_opts.repository = value;
                        else
                            warning(app.region_info.mapping.INVALID_UPDATE_REPOSITORY);
                        end
                    case "AUTO_CLEAN"
                        value = lower(setting_profile(k).extractAfter(" = "));
                        if value == "0" || value == "off" || value == "false"
                            app.update_opts.auto_clean = false;
                        elseif value == "1" || value == "on" || value == "true"
                            app.update_opts.auto_clean= true;
                        else
                            warning(app.region_info.mapping.UNRECOGNIZED_UPDATE_IDENTIFIER);
                        end
                    otherwise
                        if ~ismissing(key)
                            warning(app.region_info.mapping.UNRECOGNIZED_UPDATE_IDENTIFIER);
                        end
                end
            end

            % if auto checking update, run update checking immediately
            if app.update_opts.method == "auto"
                RunUpdateChecking(app);
            end

            function r = iscomment(str, marker)
                if ~exist("marker", "var")
                    marker = "#";
                end
                % comment means the marker is header
                r = (string(str).extractBefore(marker).replace(" ","") == "");
            end
        end

        function HoldWindowOn(app)
            % Use this function after uigetfile/uiputfile/uigetdir/uiopen
            % ... file operations, etc
            app.Reg3DV200WukongUIFigure.WindowStyle = "alwaysontop";
            app.Reg3DV200WukongUIFigure.WindowStyle = "normal";
        end

        %================== SELF DEFINATION CALL BACK ====================
       
        function ROI_Talking(app,src,evt)
            evname = evt.EventName;
            switch(evname)
                case{'MovingROI'}
                    R = ROI2Rectangle(src);
                    % convert coordinate to pixel size
                    [~,W_pix,H_pix] = Coordinate2Pixel(R,...
                        diff(app.UIAxes_Slice.XLim)/app.mov_raw.MetaData.width,...
                        diff(app.UIAxes_Slice.YLim)/app.mov_raw.MetaData.height);
                    UpdateUIAxes_Slice_Title(app,[],[],[W_pix,H_pix]);
                case{'DeletingROI'}
                    UpdateUIAxes_Slice_Title(app);
                otherwise
            end
        end

        function TimeText_ButtonDownFcn(app, event)
            switch event.Button
                case 1
                    % left button for motion tracking
                    % switch the moving status
                    app.time_text.ismoving = ~app.time_text.ismoving;
                case 2
                    % middle button for font setting
                    uisetfont(app.time_text.handle);
                case 3
                    % right button for color setting
                    uisetcolor(app.time_text.handle);
                otherwise
            end
        end

        function MetaText_ButtonDownFcn(app, event)
            switch event.Button
                case 1
                    % left button for motion tracking
                    % switch the moving status
                    app.meta_text.ismoving = ~app.meta_text.ismoving;
                case 2
                    uisetfont(app.meta_text.handle);
                case 3
                    uisetcolor(app.meta_text.handle);
                otherwise
            end
        end

        function ScaleBar_ButtonDownFcn(app, event)
            if app.scalebar.islocking == true
                return;
            end
            switch event.Button
                case 1
                    % left button for motion tracking
                    % switch the moving status
                    app.scalebar.ismoving = ~app.scalebar.ismoving;
                    % lazy update sx, sy
                    if app.scalebar.ismoving == false
                        mp = app.UIAxes_Slice.CurrentPoint(1,1:2);
                        app.scalebar.bar.sx = mp(1);
                        app.scalebar.bar.sy = mp(2);
                    end
                otherwise
            end
        end

        function Menu_MergeNoneSelected(app, event)
            event; %#ok<VUNUS> 

            app.merge_mode = "none";
            app.Menu_MergeNone.Checked = "on";
            app.Menu_MergeRB.Checked = "off";
            app.Menu_MergeGR.Checked = "off";

            RefreshViewer(app);
        end

        function Menu_MergeRBSelected(app, event)
            event; %#ok<VUNUS> 

            app.merge_mode = "red-cyan";
            app.Menu_MergeNone.Checked = "off";
            app.Menu_MergeRB.Checked = "on";
            app.Menu_MergeGR.Checked = "off";

            RefreshViewer(app);
        end

        function Menu_MergeGRSelected(app, event)
            event; %#ok<VUNUS> 

            app.merge_mode = "green-magenta";
            app.Menu_MergeNone.Checked = "off";
            app.Menu_MergeRB.Checked = "off";
            app.Menu_MergeGR.Checked = "on";

            RefreshViewer(app);
        end

    end

    %========================== CLASS METHODS ============================
    
    methods(Static)

        function results = DisableWarnings()
            warning("off", 'MATLAB:timer:miliSecPrecNotAllowed');
            warning('off', 'MATLAB:mir_warning_maybe_uninitialized_temporary');
            warning("off", 'stats:pca:ColRankDefX');
            warning("off", 'register3D:badColorChannels');
            warning('off', 'images:regmax:registrationOutBoundsTermination');
            warning('off', 'MATLAB:MKDIR:DirectoryExists');
            warning('off', 'images:imregcorr:weakPeakCorrelation');

            results = 0;
        end

        function results = EnableWarnings()
            warning("on",'MATLAB:timer:miliSecPrecNotAllowed');
            warning('on', 'MATLAB:mir_warning_maybe_uninitialized_temporary');
            warning("on",'stats:pca:ColRankDefX');
            warning("on",'register3D:badColorChannels');
            warning('on', 'images:regmax:registrationOutBoundsTermination');
            warning('on', 'MATLAB:MKDIR:DirectoryExists');
            warning('on', 'images:imregcorr:weakPeakCorrelation');

            results = 0;
        end

        function results = DeployParallelConfig()
            % load the parallel setting on different platform
            % note that app will not recover the configuration
            pinfo = struct2table(ver);
            par_flag = any(string(pinfo.Name).contains(...
                "Parallel Computing Toolbox"));

            if par_flag == true
                profs = string(parallel.listProfiles);
                if ismember("Reg3D_Local", profs) ...
                        || ismember("Reg3D_Server", profs)
                    if ismember("Reg3D_Local", profs) ...
                        && ismember("Reg3D_Server", profs)
                        error("Reg3D:invalidParallelConfiguration", ...
                            "Please keep only one parallel profile in " + ...
                            "'Cluster Profile Manager' manually.");
                    end
                    
                    if ismember("Reg3D_Local", profs) ...
                            && (parallel.defaultProfile()~="Reg3D_Local")
                        fprintf("Parallel profile changed: %s -> %s\n", ...
                            parallel.defaultProfile(), "Reg3D_Local");
                        parallel.defaultProfile("Reg3D_Local");
                    elseif ismember("Reg3D_Server", profs) ...
                            && (parallel.defaultProfile()~="Reg3D_Server")
                        fprintf("Parallel profile changed: %s -> %s\n", ...
                            parallel.defaultProfile(), "Reg3D_Server");
                        parallel.defaultProfile("Reg3D_Server");
                    end
                    results = 0;
                else
                    % get platform information and select the better
                    % configuration
                    disp("Auto deploy the parallel pool configuration at first use...");
                    sinfo = getCpuInfo();
                    if sinfo.NumProcessors > 16
                        if sinfo.OSType == "Linux"
                            disp("Reg3D_Server profile is enabled...");
                            % this machine is linux server
                            pf = parallel.importProfile(...
                                "bin\Reg3D_Server.mlsettings");
                            parallel.defaultProfile(pf); % set as default
                        else
                            % unknown work station
                            warning("Reg3D:unknownHardwarePlatform",...
                                "Unknown Platform. Reg3D_Local profile enabled.");
                            pf = parallel.importProfile(...
                                "bin\Reg3D_Local.mlsettings");
                            parallel.defaultProfile(pf); % set as default
                        end
                    else
                        % no matter local machine OS
                        disp("Reg3D_Local profile is enabled...");
                        pf = parallel.importProfile(...
                            "bin\Reg3D_Local.mlsettings");
                        parallel.defaultProfile(pf); % set as default
                    end
                    disp("Done.");
                    results = 0;
                end
            else
                results = -1;
            end
        end
    end
    
    %=================== INTERFACE FOR SUBAPP ============================
    
    methods (Access = public)
        
        function status = GenSysMsg(app, cmdstrs, out, timestamp)
            arguments
                app (1,1) Register;
                cmdstrs (1,2) string;
                out (1,1) logical = true;
                timestamp (1,1) logical = true;
            end

            if app.Switch_Log.Value == "off"
                status = size(app.system_message, 1);
                return;
            end

            % record the message
            if timestamp == true
                app.system_message = [app.system_message; ...
                    cmdstrs(1), cmdstrs(2), string(datetime("now"))];
            else
                app.system_message = [app.system_message; ...
                    cmdstrs(1), cmdstrs(2), ""];
            end

            % apply output
            if out == true
                t_msg = AppendTextArea_Log(app, ...
                    app.region_info.mapping.(cmdstrs(1))+cmdstrs(2),...
                    timestamp);
                app.system_message(end, 3) = string(t_msg);
            end

            status = size(app.system_message, 1);
        end

        function SetBCG(app, params)
            app.view_opts = params;

            % refresh viewer
            RefreshViewer(app);
        end

        function params = GetBCG(app)
            params = app.view_opts;
        end

        function SetRegParams(app, params, tmp_sync)
            app.reg_opts = params;
            if exist("tmp_sync","var")
                app.template_sync = tmp_sync;
            end
        end

        function [params, tmp_sync] = GetRegParams(app)
            params = app.reg_opts;
            tmp_sync = app.template_sync;
        end

        function SetScaleBar(app, scale, bar)
            app.scalebar.scale = scale;
            app.scalebar.bar = bar;

            % refresh scalebar
            DrawScaleBar(app);
        end

        function param = GetScaleBar(app)
            param.scale = app.scalebar.scale;
            param.bar = app.scalebar.bar;
        end

        function slice_img = GetCurrentSliceImg(app)
            slice_img = GetSliceImgAt(app);
        end

        function slice_img = GetSliceImgAt(app, z, t)
            arguments
                app (1,1) Register;
                z = app.slice_idx_cur;
                t (1,1) = app.frame_idx_cur;
            end

            if app.zproj_mode == "none"
                switch app.viewer_src
                    case "raw"
                        slice_img = app.mov_raw.Movie(:,:,app.channel_idx_cur,...
                            z, t);
                    case "aligned"
                        slice_img = app.mov_aligned.Movie(:,:,app.channel_idx_cur,...
                            z, t);
                end
            else
                switch app.viewer_src
                    case "raw"
                        slice_img = app.zprojimg.raw(:,:,...
                            app.channel_idx_cur, t);
                    case "aligned"
                        slice_img = app.zprojimg.aligned(:,:,...
                            app.channel_idx_cur, t);
                end
            end
        end

        function c = GetCurrentChannel(app)
            c = app.channel_order(app.channel_idx_cur);
        end

        function z = GetCurrentSliceIndex(app)
            z = app.slice_idx_cur;
        end

        function f = GetCurrentFrameIndex(app)
            f = app.frame_idx_cur;
        end

        function roi = GetROI(app)
            if ~isempty(app.roi_handle) && isvalid(app.roi_handle)
                roi = app.roi_handle;
            else
                roi = [];
            end
        end

        function tform = GetTransformation(app)
            tform = app.mov_aligned.Transformation;
        end

        function [mov_raw, mov_align] = GetMoviePair(app)
            mov_raw = squeeze(app.mov_raw.Movie(:,:,app.channel_idx_cur,:,:));
            mov_align = squeeze(app.mov_aligned.Movie(:,:,app.channel_idx_cur,:,:));
        end

        function tempvol = GetTemplate(app)
            tempvol = GetFixedVol(squeeze(app.mov_raw.Movie(:,:,app.channel_idx_cur,:,:)),...
                app.reg_opts.volfixed.G,"off");
        end

        function info = GetImgInfo(app)
            info = app.mov_raw.MetaData;
        end

        function fname = GetFileName(app)
            fname = string(app.filename);
        end

        function [lang, mapping] = GetLangsInfo(app)
            lang = app.region_info.lang;
            mapping = app.region_info.mapping;
        end

        function SendMessage(app, id, timeStamp)
            GenSysMsg(app, id, true, timeStamp);
        end

        function SetFrames(app, indices)
            app.roi_cur.f = indices;
        end

        function SetChannelAlignmentView(app, shift)
            UpdateCurrentSlice(app, "CA", shift);

            UpdateUIAxes_Slice(app);
        end

        function SetChannelAlignment(app, shift)
            if any(shift ~= 0)
                selection = uiconfirm(app.Reg3DV200WukongUIFigure,...
                    app.region_info.mapping.IRREVERSIBLE_CA,...
                    app.region_info.mapping.WARNING, "Icon","warning");
                if selection ~= "OK"
                    return;
                else
                    PanelWaiting(app);

                    % apply channel shift on data
                    RA = imref3d([app.mov_raw.MetaData.height, ...
                        app.mov_raw.MetaData.width, ...
                        app.mov_raw.MetaData.slices], ...
                        app.mov_raw.MetaData.xRes, ...
                        app.mov_raw.MetaData.yRes, ...
                        app.mov_raw.MetaData.zRes);
                    rot = [cosd(shift(3)), sind(shift(3)), 0; ...
                        -sind(shift(3)), cosd(shift(3)), 0; ...
                        0, 0, 1];
                    tform = rigid3d(rot, [shift(1:2), 0]);
                    [~, cidx] = ismember(app.reg_opts.Chls, app.mov_raw.MetaData.cOrder);
                    fill_val = prctile(app.mov_raw.Movie(:,:,cidx,:,1), 10, "all");

                    bar = uiprogressdlg(app.Reg3DV200WukongUIFigure,...
                        "Title",app.region_info.mapping.INFORMATION, ...
                        "Message",app.region_info.mapping.CHANNEL_ALIGNING,...
                        "Indeterminate","on", "Icon","info");
                    drawnow;

                    parpool("Threads");

                    tmpmov = squeeze(app.mov_raw.Movie(:,:,cidx,:,:));
                    parfor n = 1:app.mov_raw.MetaData.frames
                        tmp_data = tmpmov(:,:,:,n);
                        tmpmov(:,:,:,n) =  imwarp(tmp_data, RA, tform,...
                            "linear","FillValues",fill_val, "OutputView",RA);
                        % update bar processing
                        % bar.Send; %#ok<PFBNS>
                    end
                    app.mov_raw.Movie(:,:,cidx,:,:) = tmpmov;

                    if ~isempty(app.mov_aligned)
                        tmpmov = squeeze(app.mov_aligned.Movie(:,:,cidx,:,:));
                        parfor n = 1:app.mov_aligned.MetaData.frames
                            tmp_data = tmpmov(:,:,:,n);
                            tmpmov(:,:,:,n) =  imwarp(tmp_data, RA, tform,...
                                "linear","FillValues",fill_val, "OutputView",RA);
                            % update bar processing
                            % bar.Send; %#ok<PFBNS>
                        end
                        app.mov_aligned.Movie(:,:,cidx,:,:) = tmpmov;
                    end

                    delete(gcp("nocreate"));

                    close(bar);

                    PanelRecovered(app);

                    % Synchronize z projection
                    SourceZProjection(app, [], app.zproj_mode);
                end
            end

            % RefreshViewer for viewing recovery
            RefreshViewer(app);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Register.DisableWarnings();
            Register.DeployParallelConfig();

            DeployEnvConfig(app);
            InitData(app);
            InitTimer(app);
            SetSystemLanguage(app);
            InitPanels(app);
            ValidateUpdate(app);

            GenSysMsg(app, ["UI_INITIALIZE_SUCCESS",""]);
        end

        % Menu selected function: Menu_LoadFile
        function Menu_LoadFileSelected(app, event)
            [opts, rt, memptr, app.filename] = loadfile([],false, app.memmap_flag, 0);

            % load variables to app workspace
            status = MemLoader(app, memptr, opts, rt);
            if status ~= 0
                return;
            end

            GenSysMsg(app, ["FILE_LOADING_COMPLETE", " "+app.filename]);
           
            % update the system exterior
            UpdateSystemAfterLoading(app);
            GenSysMsg(app, ["SYS_INITIALIZE_SUCCESS",""]);

        end

        % Menu selected function: Menu_SaveImageAs
        function Menu_SaveImageAsSelected(app, event)
            if ~isempty(app.filename) && exist(app.filename,"file")
                [~,fname,~] = fileparts(app.filename);
            elseif ~isempty(app.varname)
                fname = app.varname(1);
            else
                fname = "processing";
            end
            if any(app.img_operators~="none")
                fname = fname + "_" + app.img_operators(app.img_operators~="none").join("-");
            end
            bar = uiprogressdlg(app.Reg3DV200WukongUIFigure, ...
                "Title", app.region_info.mapping.EXPORT_AS, ...
                "Message", app.region_info.mapping.EXPORTING,...
                "Indeterminate","on");

            PanelWaiting(app);

            if ~isempty(app.Menu_Red) && isvalid(app.Menu_Red) ...
                    && app.Menu_Red.Checked
                c_cur = "r";
            elseif ~isempty(app.Menu_Green) && isvalid(app.Menu_Green) ...
                    && app.Menu_Green.Checked
                c_cur = "g";
            end
            [~, cidx] = ismember(c_cur, app.channel_order);

            if numel(app.channel_order) == 2
                if c_cur == app.reg_opts.Chla
                    ftype = "structured";
                else
                    ftype = "functional";
                end
            else
                ftype = "functional";
            end

            switch app.viewer_src
                case "raw"
                    operation_save = app.img_operators(app.img_operators~="aligned").join("-");
                    data_export = squeeze(app.mov_raw.Movie(:,:,cidx,:,:));
                    meta_data = struct("ftype",ftype, ...
                                       "operation",operation_save);
                    fname_export = fname+"_"+ftype;
                case "aligned"
                    operation_save = app.img_operators.join("-");
                    data_export = squeeze(app.mov_aligned.Movie(:,:,cidx,:,:));
                    meta_data = struct("ftype",ftype, ...
                                       "operation",operation_save);
                    fname_export = fname+"_aligned_"+ftype;
            end
            [status, app.sv_path] = savefile(data_export,[],...
                'filename', fname_export, ...
                'metadata', meta_data);

            if status == 0
                GenSysMsg(app, ["IMAGE_EXPORT_SUCCESS",""]);
            else
                app.sv_path = "";
            end

            close(bar);
            PanelRecovered(app);
        end

        % Menu selected function: Menu_CropT
        function Menu_CropTSelected(app, event)
            selection = uiconfirm(app.Reg3DV200WukongUIFigure,...
                app.region_info.mapping.IRREVERSIBLE_CROP,...
                app.region_info.mapping.WARNING, "Icon","warning");
            if selection == "OK"
                % record old frames selected
                f_old = app.roi_cur.f;

                % call the GUI version at first
                if isempty(app.FS_handle) || ~isvalid(app.FS_handle)
                    app.FS_handle = FrameSelecter(app);
                    waitfor(app.FS_handle);
                end

                if numel(f_old)==numel(app.roi_cur.f) ...
                        && all(f_old == app.roi_cur.f)
                    % no change, show the old version selection
                    prompt = {['\fontsize{12} ', app.region_info.mapping.TIME_RANGE.char()]};
                    dlgtitle = app.region_info.mapping.CROP_FRAME.char();
                    dims = [1 40];
                    definput = {'1:end'};
                    dlgopts.WindowStyle = 'normal';
                    dlgopts.Interpreter = "tex";
                    dlgout = inputdlg(prompt,dlgtitle,dims,definput,dlgopts);
                    if isempty(dlgout)
                        return;
                    else
                        dlgout=dlgout{1};
                    end
                    dlgout = string(dlgout).replace("end",num2str(app.mov_raw.MetaData.frames));
                    app.roi_cur.f = str2num(dlgout); %#ok<ST2NM>
                end

                % validate selected frames
                if numel(app.roi_cur.f) < 2
                    uialert(app.Reg3DV200WukongUIFigure, ...
                        app.region_info.mapping.FRAME_FEW, ...
                        app.region_info.mapping.WARNING, "Icon","warning");
                    return;
                elseif numel(app.roi_cur.f) > app.mov_raw.MetaData.frames
                    uialert(app.Reg3DV200WukongUIFigure, ...
                        app.region_info.mapping.FRAME_OVERFLOW, ...
                        app.region_info.mapping.WARNING, "Icon","warning");
                    return;
                elseif numel(app.roi_cur.f) == app.mov_raw.MetaData.frames
                    uialert(app.Reg3DV200WukongUIFigure, ...
                        app.region_info.mapping.GIVEUP_CROP,...
                        app.region_info.mapping.INFORMATION, "Icon","info");
                    return;
                end

                PanelWaiting(app);  %

                % Crop the source movie and time
                [D, L] = CropT(app.mov_raw.Movie,...
                    app.roi_cur.f);
                app.mov_raw.Movie = D;
                app.mov_raw.MetaData.frames = L;
                app.mov_raw.Time = app.mov_raw.Time(app.roi_cur.f);
                app.mov_raw.Transformation ...
                    = app.mov_raw.Transformation(app.roi_cur.f,:);

                % Crop the aligned movie if it's possible
                % for aligned info
                if ~isempty(app.mov_aligned)
                    [D ,~] = CropT(app.mov_aligned.Movie,...
                            app.roi_cur.f);
                    app.mov_aligned.Movie = D;
                    app.mov_aligned.MetaData.frames = L;
                    app.mov_aligned.Time = app.mov_aligned.Time(app.roi_cur.f);
                    app.mov_aligned.Transformation ...
                        = app.mov_aligned.Transformation(app.roi_cur.f,:);
                end

                % for recovery point
                if ~isempty(app.mdrp.raw)
                    [app.mdrp.raw, ~] = CropT(app.mdrp.raw,...
                            app.roi_cur.f);
                end
                if ~isempty(app.mdrp.aligned)
                    [app.mdrp.aligned, ~] = CropT(app.mdrp.aligned,...
                            app.roi_cur.f);
                end

                % crop text vector if is needed
                if ~isempty(app.time_text.text)
                    app.time_text.text = app.time_text.text(app.roi_cur.f);
                end
                if ~isempty(app.meta_text.text)
                    app.meta_text.text = app.meta_text.text(app.roi_cur.f);
                end

                % crop the z-projection
                if ~isempty(app.zprojimg.raw)
                    [app.zprojimg.raw, ~] = CropT(...
                        app.zprojimg.raw, app.roi_cur.f);
                end
                if ~isempty(app.zprojimg.aligned)
                    [app.zprojimg.aligned, ~] = CropT(...
                        app.zprojimg.aligned, app.roi_cur.f);
                end

                % reorder time axis
                app.frame_idx_cur = 1;
                SetSlider_Time(app,"on",[1,L]);

                % Synchronize z projection
                SourceZProjection(app, [], app.zproj_mode);

                % Synchronize registration frames
                app.reg_frames = 1:L;
                GenSysMsg(app, ["RESET_REGISTRATION_FRAMES",""]);

                PanelRecovered(app);    %

                app.img_operators = setdiff(union(app.img_operators, "cut"),"none");
                GenSysMsg(app, ["T_CROPPED",""]);
                RefreshViewer(app);
            else
                return;
            end
        end

        % Menu selected function: Menu_About
        function Menu_AboutSelected(app, event)
            msg = sprintf(['Register3D Graphics User Interface (RGUI) \n' ...
                'Full function volume series Register \n' ...
                'Version: 2.0.0(Wukong) preview @si-lab \n' ...
                'Author: Weihan Li \n' ...
                'E-mail: liweihan20@mails.ucas.ac.cn']);
            uialert(app.Reg3DV200WukongUIFigure, msg, ...
                app.region_info.mapping.INFORMATION, "Icon","info");
        end

        % Menu selected function: Menu_Documents
        function Menu_DocumentsSelected(app, event)
            if isunix
                % for unix system
                help_file = 'help/help.html';
            else
                % for windows system
                help_file = 'help\help.html';
            end
            
            if ~exist(help_file, "file")
                uialert(app.Reg3DV200WukongUIFigure, ...
                    app.region_info.mapping.MISSING_HELP, ...
                    app.region_info.mapping.ERROR, "Icon","error");
                return;
            else
                web(help_file);
            end
        end

        % Value changed function: Slider_Slice
        function Slider_SliceValueChanged(app, event)
            value = round(app.Slider_Slice.Value);
            app.Slider_Slice.Value = value;

            % Synchronize the slice spinner
            app.Spinner_Slice.Value = value;
            app.slice_idx_cur = value;

            RefreshViewer(app);
        end

        % Value changed function: Slider_Time
        function Slider_TimeValueChanged(app, event)
            value = round(app.Slider_Time.Value);
            app.Slider_Time.Value = value;

            % Synchronize the time spinner
            app.Spinner_Time.Value = value;
            app.frame_idx_cur = value;

            RefreshViewer(app);
        end

        % Value changed function: Spinner_Slice
        function Spinner_SliceValueChanged(app, event)
            value = round(app.Spinner_Slice.Value);
            app.Spinner_Slice.Value = value;

            % Synchronize the slice slider
            app.Slider_Slice.Value = value;
            app.slice_idx_cur = value;
            
            RefreshViewer(app);
        end

        % Callback function
        function Menu_RedSelected(app, event)
            [~, app.channel_idx_cur] = ismember("r",app.channel_order);

            if numel(app.channel_order) == 2
                app.Menu_Red.Checked = "on";
                app.Menu_Green.Checked = "off";

                RefreshViewer(app);
            end

        end

        % Callback function
        function Menu_GreenSelected(app, event)
            [~, app.channel_idx_cur] = ismember("g",app.channel_order);

            if numel(app.channel_order) == 2
                app.Menu_Red.Checked = "off";
                app.Menu_Green.Checked = "on";

                RefreshViewer(app);
            end

        end

        % Value changed function: DropDown_Source
        function DropDown_SourceValueChanged(app, event)
            value = app.DropDown_Source.Value;
            switch value
                case "RAW"
                    app.viewer_src = "raw";
                    if app.Menu_ZProjection.Checked == true
                        if isempty(app.zprojimg.raw)
                            SourceZProjection(app);
                        else
                            SourceZProjection(app, app.frame_idx_cur);
                        end
                    end
                case "ALIGNED"
                    app.viewer_src = "aligned";
                    if app.Menu_ZProjection.Checked == true
                        if isempty(app.zprojimg.aligned)
                            SourceZProjection(app);
                        else
                            SourceZProjection(app, app.frame_idx_cur);
                        end
                    end
                otherwise
            end

            RefreshViewer(app);
        end

        % Value changed function: Spinner_Time
        function Spinner_TimeValueChanged(app, event)
            value = round(app.Spinner_Time.Value);
            app.Spinner_Time.Value = value;

            % Synchronize the slice slider
            app.Slider_Time.Value = value;
            app.frame_idx_cur = value;

            RefreshViewer(app);
        end

        % Menu selected function: Menu_RectangleROI
        function Menu_RectangleROISelected(app, event)
            if ~isempty(app.roi_handle) && isvalid(app.roi_handle)
                delete(app.roi_handle);
            end
            app.roi_handle = drawrectangle(app.UIAxes_Slice);
            addlistener(app.roi_handle,'MovingROI',...
                @(src,evt)ROI_Talking(app,src,evt));
            addlistener(app.roi_handle,'DeletingROI',...
                @(src,evt)ROI_Talking(app,src,evt));
        end

        % Menu selected function: Menu_CircleROI
        function Menu_CircleROISelected(app, event)
            if ~isempty(app.roi_handle) && isvalid(app.roi_handle)
                delete(app.roi_handle);
            end
            app.roi_handle = drawcircle(app.UIAxes_Slice);
            addlistener(app.roi_handle,'MovingROI',...
                @(src,evt)ROI_Talking(app,src,evt));
            addlistener(app.roi_handle,'DeletingROI',...
                @(src,evt)ROI_Talking(app,src,evt));
        end

        % Menu selected function: Menu_PolygonROI
        function Menu_PolygonROISelected(app, event)
            if ~isempty(app.roi_handle) && isvalid(app.roi_handle)
                delete(app.roi_handle);
            end
            app.roi_handle = drawpolygon(app.UIAxes_Slice);
            addlistener(app.roi_handle,'MovingROI',...
                @(src,evt)ROI_Talking(app,src,evt));
            addlistener(app.roi_handle,'DeletingROI',...
                @(src,evt)ROI_Talking(app,src,evt));
        end

        % Menu selected function: Menu_FreehandROI
        function Menu_FreehandROISelected(app, event)
            if ~isempty(app.roi_handle) && isvalid(app.roi_handle)
                delete(app.roi_handle);
            end
            app.roi_handle = drawfreehand(app.UIAxes_Slice);
            addlistener(app.roi_handle,'MovingROI',...
                @(src,evt)ROI_Talking(app,src,evt));
            addlistener(app.roi_handle,'DeletingROI',...
                @(src,evt)ROI_Talking(app,src,evt));
        end

        % Menu selected function: Menu_SaveSetting
        function Menu_SaveSettingSelected(app, event)
            t = datetime('now','TimeZone','local','Format','yyMMdd');

            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));

            [name,path] = uiputfile({'*.reg3d', ...
                [app.region_info.mapping.REG3D_CONFIGURATION.char(), '(*.reg3d)']},...
                    app.region_info.mapping.SAVE_CONFIGURATION_FILE.char(),...
                    pathToMLAPP+"\bin\"+string(t)+".reg3d");
            if ~isequal(name,0)
                file_name = fullfile(path,name);
                SavePreset(app,file_name);
            end
        end

        % Menu selected function: Menu_LoadSetting
        function Menu_LoadSettingSelected(app, event)
            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));

            [name,path] = uigetfile({'*.reg3d', ...
                [app.region_info.mapping.REG3D_CONFIGURATION.char(), '(*.reg3d)']},...
                    app.region_info.mapping.READ_CONFIGURATION_FILE.char(),...
                    pathToMLAPP+"\bin\");
            HoldWindowOn(app);

            if ~isequal(name,0)
                file_name = fullfile(path,name);
                preset = LoadPreset(app, file_name);
                if ~isempty(preset)
                    if ~isempty(app.RS_handle) && isvalid(app.RS_handle)
                        % refresh the RS_handle
                        app.RS_handle.Refresh();
                    end
                    % update playing menu setting
                    app.fps.fold = preset.fps_fold;
                    app.Menu_1F.Checked = "off";app.Menu_5F.Checked = "off";
                    app.Menu_25F.Checked = "off";
                    switch app.fps.fold
                        case 1
                            app.Menu_1F.Checked = "on";
                        case 5
                            app.Menu_5F.Checked = "on";
                        case 25
                            app.Menu_25F.Checked = "on";
                    end
                    app.key_mode = preset.key_mode;
                    app.Menu_Direct.Checked = "off";app.Menu_Loop.Checked = "off";
                    switch app.key_mode
                        case "one-step"
                            app.Menu_Direct.Checked = "on";
                        case "loop"
                            app.Menu_Loop.Checked = "on";
                    end
                end
            end 
        end

        % Menu selected function: Menu_Horizontal
        function Menu_HorizontalSelected(app, event)
            app.Menu_Horizontal.Checked = ~app.Menu_Horizontal.Checked;

            switch app.viewer_src
                case "raw"
                    app.mov_raw.Movie = fliplr(app.mov_raw.Movie);
                    % Synchronize z projection
                    if app.zproj_mode ~= "none"
                        app.zprojimg.raw = fliplr(app.zprojimg.raw);
                    end
                case "aligned"
                    app.mov_aligned.Movie = fliplr(app.mov_aligned.Movie);
                    if app.zproj_mode ~= "none"
                        app.zprojimg.aligned = fliplr(app.zprojimg.aligned);
                    end
                otherwise
            end
                
            RefreshViewer(app);
        end

        % Menu selected function: Menu_Vertical
        function Menu_VerticalSelected(app, event)
            app.Menu_Vertical.Checked = ~app.Menu_Vertical.Checked;
            
            switch app.viewer_src
                case "raw"
                    app.mov_raw.Movie = flipud(app.mov_raw.Movie);
                    % Synchronize z projection
                    if app.zproj_mode ~= "none"
                        app.zprojimg.raw = flipud(app.zprojimg.raw);
                    end
                case "aligned"
                    app.mov_aligned.Movie = flipud(app.mov_aligned.Movie);
                    if app.zproj_mode ~= "none"
                        app.zprojimg.aligned = flipud(app.zprojimg.aligned);
                    end
            end

            RefreshViewer(app);
        end

        % Menu selected function: Menu_1F
        function Menu_1FSelected(app, event)
            app.fps.fold = 1;
            app.player_timer.Period = 1/(app.fps.base*app.fps.fold);
            app.Menu_1F.Checked = "on";
            app.Menu_5F.Checked = "off";
            app.Menu_25F.Checked = "off";
        end

        % Menu selected function: Menu_5F
        function Menu_5FSelected(app, event)
            app.fps.fold = 5;
            app.player_timer.Period = 1/(app.fps.base*app.fps.fold);
            app.Menu_1F.Checked = "off";
            app.Menu_5F.Checked = "on";
            app.Menu_25F.Checked = "off";
        end

        % Menu selected function: Menu_25F
        function Menu_25FSelected(app, event)
            app.fps.fold = 25;
            app.player_timer.Period = 1/(app.fps.base*app.fps.fold);
            app.Menu_1F.Checked = "off";
            app.Menu_5F.Checked = "off";
            app.Menu_25F.Checked = "on";
        end

        % Key press function: Reg3DV200WukongUIFigure
        function Reg3DV200WukongUIFigureKeyPress(app, event)
            key = event.Key;            % base key
            modifier = event.Modifier;  % modifier, can be any combination 
                                        % comes from ["control","alt","shift"] 

            if ~isempty(modifier) && all(modifier=="control")
                switch key
                    case {"a","A"}
                        if ~isempty(app.FS_handle) && isvalid(app.FS_handle)
                            app.FS_handle.AppendFrame(app.frame_idx_cur);
                        end
                    otherwise
                end
            elseif isempty(modifier)
                switch key
                    case {"d","D","rightarrow"}
                        NextFramePtr(app);
                    case {"a","A","leftarrow"}
                        PrevFramePtr(app);
                    case {"w","W","uparrow"}
                        NextSlicePtr(app);
                    case {"s","S","downarrow"}
                        PrevSlicePtr(app);
                    case "space"
                        if app.isplaying
                            stop(app.player_timer);
                            app.isplaying = false;
                        else
                            LoopN = app.Slider_Time.Limits(end) - app.frame_idx_cur;
                            switch app.key_mode
                                case "one-step"
                                    if isempty(LoopN) || (LoopN==0)
                                        % reject start timer
                                        return;
                                    end
                                case "loop"
                                    LoopN = Inf;
                            end

                            app.player_timer.TasksToExecute = LoopN;
                            LockPlaySetting(app);

                            app.isplaying = true;
                            start(app.player_timer);
                        end
                    otherwise
                end
            end

            RefreshViewer(app);
        end

        % Menu selected function: Menu_Direct
        function Menu_DirectSelected(app, event)
            app.Menu_Direct.Checked = true;
            app.Menu_Loop.Checked = false;
            app.key_mode = "one-step";
            app.player_timer.TasksToExecute = app.Slider_Time.Limits(end) ...
                - app.frame_idx_cur;
        end

        % Menu selected function: Menu_Loop
        function Menu_LoopSelected(app, event)
            app.Menu_Direct.Checked = false;
            app.Menu_Loop.Checked = true;
            app.key_mode = "loop";
            app.player_timer.TasksToExecute = Inf;
        end

        % Menu selected function: Menu_CropXY
        function Menu_CropXYSelected(app, event)
            if ~isempty(app.roi_handle) && isvalid(app.roi_handle)
                selection = uiconfirm(app.Reg3DV200WukongUIFigure,...
                app.region_info.mapping.IRREVERSIBLE_CROP,...
                app.region_info.mapping.WARNING, "Icon","warning");
                if selection == "OK"
                    roiXY_coor = ROI2Rectangle(app.roi_handle);
                    % xy := [x1,y1;x2,y2] in pixel
                    app.roi_cur.xy = Coordinate2Pixel(roiXY_coor,...
                        diff(app.UIAxes_Slice.XLim)/app.mov_raw.MetaData.width,...
                        diff(app.UIAxes_Slice.YLim)/app.mov_raw.MetaData.height);

                    PanelWaiting(app);  %

                    % croping three sources if it's possible
                    [D, s] = CropXY(app.mov_raw.Movie,...
                        app.roi_cur.xy);
                    app.mov_raw.Movie = D;
                    app.mov_raw.MetaData.width = s.width;
                    app.mov_raw.MetaData.height = s.height;

                    % for aligned info
                    if ~isempty(app.mov_aligned)
                        [D, ~] = CropXY(app.mov_aligned.Movie,...
                            app.roi_cur.xy);
                        app.mov_aligned.Movie = D;
                        % crop the non-rigid registration displacement field
                        for f = 1:app.mov_aligned.MetaData.frames
                            if ~isempty(app.mov_aligned.Transformation{f,2})
                                [app.mov_aligned.Transformation{f,2}, ~] = CropXY(...
                                    app.mov_aligned.Transformation{f, 2},...
                                    app.roi_cur.xy);
                            end
                        end
                        app.mov_aligned.MetaData.width = s.width;
                        app.mov_aligned.MetaData.height = s.height;
                    end

                    % for recovery point
                    if ~isempty(app.mdrp.raw)
                        [app.mdrp.raw, ~] = CropXY(app.mdrp.raw,...
                            app.roi_cur.xy);
                    end
                    if ~isempty(app.mdrp.aligned)
                        [app.mdrp.aligned, ~] = CropXY(app.mdrp.aligned,...
                            app.roi_cur.xy);
                    end

                    % crop the z-projection
                    if ~isempty(app.zprojimg.raw)
                        [app.zprojimg.raw, ~] = CropXY(...
                            app.zprojimg.raw, app.roi_cur.xy);
                    end
                    if ~isempty(app.zprojimg.aligned)
                        [app.zprojimg.aligned, ~] = CropXY(...
                            app.zprojimg.aligned, app.roi_cur.xy);
                    end

                    % Synchronize z projection
                    SourceZProjection(app, [], app.zproj_mode);

                    % remove the exist ROIs if possible
                    delete(app.roi_handle);

                    % Reset the Scale Bar
                    % cause the rubbsh auto collection, throw the pointers
                    app.scalebar = struct("handle_x",[],...   % line object
                        "handle_y",[],...
                        "txtobj_x",[],...  % text object
                        "txtobj_y",[],...
                        "scale",app.scalebar.scale,...
                        "bar",struct("xlen",app.scalebar.bar.xlen,...
                        "ylen",app.scalebar.bar.ylen,"ishor",false,"isver",false,...
                        "linw",1,"color","w","mk","none","sx",40,"sy",40,...
                        "mk_fill",true,"mk_size",3,"txt_space",app.scalebar.bar.txt_space,...
                        "font",struct("FontName",'Arial',"FontWeight",'bold',...
                        "FontAngle",'normal',"FontUnits",'points',"FontSize",10)),...
                        "ismoving",[],"islocking",false);
                    app.scalebar.bar.xlen = min(app.scalebar.bar.xlen, ...
                        0.5*app.mov_raw.MetaData.width*app.mov_raw.MetaData.xRes);
                    app.scalebar.bar.ylen = min(app.scalebar.bar.ylen, ...
                        0.5*app.mov_raw.MetaData.height*app.mov_raw.MetaData.yRes);
                    app.scalebar.bar.sy = app.mov_raw.MetaData.height - app.scalebar.bar.sx;
                    app.scalebar.sf = min(get(0).ScreenSize(3:4)./...
                        [app.mov_raw.MetaData.width, app.mov_raw.MetaData.height]);

                    PanelRecovered(app);

                    app.img_operators = setdiff(union(app.img_operators, "crop"), "none");
                    GenSysMsg(app, ["XY_CROPPED",""]);
                    RefreshViewer(app);
                else
                    return;
                end
            else
                uialert(app.Reg3DV200WukongUIFigure,...
                    app.region_info.mapping.CONFIRM_ROI_RANGE,...
                    app.region_info.mapping.WARNING, "Icon","warning");
                return;
            end
        end

        % Menu selected function: Menu_CropZ
        function Menu_CropZSelected(app, event)
            selection = uiconfirm(app.Reg3DV200WukongUIFigure,...
                app.region_info.mapping.IRREVERSIBLE_CROP,...
                app.region_info.mapping.WARNING, "Icon","warning");
            if selection == "OK"
                prompt = {['\fontsize{12} ', app.region_info.mapping.THICKNESS_RANGE.char()]};
                dlgtitle = app.region_info.mapping.CROP_Z.char();
                dims = [1 40];
                definput = {'1:end'};
                dlgopts.WindowStyle = 'normal';
                dlgopts.Interpreter = "tex";
                dlgout = inputdlg(prompt,dlgtitle,dims,definput,dlgopts);
                if isempty(dlgout)
                    return;
                else
                    dlgout=dlgout{1};
                end
                dlgout = string(dlgout).replace("end",num2str(app.mov_raw.MetaData.slices));
                app.roi_cur.z = str2num(dlgout); %#ok<ST2NM>
                if numel(app.roi_cur.z) < 2
                    uialert(app.Reg3DV200WukongUIFigure, ...
                        app.region_info.mapping.SLICE_FEW,...
                        app.region_info.mapping.WARNING, "Icon","warning");
                    return;
                elseif numel(app.roi_cur.z) < 5
                    slt = uiconfirm(app.Reg3DV200WukongUIFigure,...
                        app.region_info.mapping.SLICES_FEW_FOR_NEXT_STEP, ...
                        app.region_info.mapping.WARNING, "Icon","warning");
                    if slt ~= "OK"
                        return;
                    end
                elseif numel(app.roi_cur.z) > app.mov_raw.MetaData.slices
                    uialert(app.Reg3DV200WukongUIFigure, ...
                        app.region_info.mapping.SLICES_OVERFLOW,...
                        app.region_info.mapping.WARNING, "Icon","warning");
                    return;
                end

                PanelWaiting(app);  %

                % croping two sources if it's possible
                [D, L] = CropZ(app.mov_raw.Movie,...
                    app.roi_cur.z);
                app.mov_raw.Movie = D;
                app.mov_raw.MetaData.slices = L;

                % for aligned info
                if ~isempty(app.mov_aligned)
                    [D, ~] = CropZ(app.mov_aligned.Movie,...
                        app.roi_cur.z);
                    app.mov_aligned.Movie = D;
                    app.mov_aligned.MetaData.slices = L;
                end

                % for recovery point
                if ~isempty(app.mdrp.raw)
                    [app.mdrp.raw, ~] = CropZ(app.mdrp.raw,...
                        app.roi_cur.z);
                end
                if ~isempty(app.mdrp.aligned)
                    [app.mdrp.aligned, ~] = CropZ(app.mdrp.aligned,...
                        app.roi_cur.z);
                end

                % reorder slice axis
                app.slice_idx_cur = 1;
                SetSlider_Slice(app);

                % reset valid vpl
                app.reg_opts.VPL_Rigid = ...
                    floor(log(app.mov_raw.MetaData.slices)/log(4))+1;
                app.reg_opts.VPL_NonRigid = app.reg_opts.VPL_Rigid;

                % Synchronize z projection
                SourceZProjection(app, [], app.zproj_mode);

                PanelRecovered(app);

                app.img_operators = setdiff(union(app.img_operators, "crop"), "none");
                GenSysMsg(app, ["Z_CROPPED",""]);
                RefreshViewer(app);
            else
                return;
            end
        end

        % Menu selected function: Menu_LightContrast
        function Menu_LightContrastSelected(app, event)
            if isempty(app.BC_handle) || ~isvalid(app.BC_handle)
                app.BC_handle = BC(app);
            end
        end

        % Window button motion function: Reg3DV200WukongUIFigure
        function Reg3DV200WukongUIFigureWindowButtonMotion(app, event)
            % This callback offer realtime cursor position
            if app.is_data_loaded == true
                % skip if ROI mode
                if ~isempty(app.roi_handle) && isvalid(app.roi_handle)
                    return;
                end
                mouse_pos_cur = app.UIAxes_Slice.CurrentPoint(1,1:2);
                if any(mouse_pos_cur < 0) ...
                        || any(mouse_pos_cur>[app.mov_raw.MetaData.width,...
                        app.mov_raw.MetaData.height])
                    mouse_pos_cur = [];
                else
                    % change the unit of mouse cursor position from world
                    % coordinate to image coordinate
                    R = [0,0;mouse_pos_cur];    % generate fixed vector R
                    [~,x,y] = Coordinate2Pixel(R,...
                        diff(app.UIAxes_Slice.XLim)/app.mov_raw.MetaData.width,...
                        diff(app.UIAxes_Slice.YLim)/app.mov_raw.MetaData.height);
                    mouse_pos_cur = [x, y];          
                end

                if isempty(mouse_pos_cur)
                    % recover the none title
                    UpdateUIAxes_Slice_Title(app);
                    return;
                end

                % readout pixel intensity from slice
                pixIxy = 0;
                if app.zproj_mode == "none"
                    switch app.viewer_src
                        case "raw"
                            pixIxy = app.mov_raw.Movie(y,x,app.channel_idx_cur,...
                                app.slice_idx_cur,app.frame_idx_cur);
                        case "aligned"
                            pixIxy = app.mov_aligned.Movie(y,x,app.channel_idx_cur,...
                                app.slice_idx_cur,app.frame_idx_cur);
                        otherwise
                    end
                else
                    switch app.viewer_src
                        case "raw"
                            pixIxy = app.zprojimg.raw(y,x,...
                                app.channel_idx_cur,app.frame_idx_cur);
                        case "aligned"
                            pixIxy = app.zprojimg.aligned(y,x,...
                                app.channel_idx_cur,app.frame_idx_cur);
                        otherwise
                    end
                end

                UpdateUIAxes_Slice_Title(app,mouse_pos_cur,pixIxy);

                % check the text is moving
                if ~isempty(app.time_text.ismoving) && app.time_text.ismoving
                    app.time_text.handle.Position = [mouse_pos_cur, 0];
                end
                if ~isempty(app.meta_text.ismoving) && app.meta_text.ismoving
                    app.meta_text.handle.Position = [mouse_pos_cur, 0];
                end
                if ~isempty(app.scalebar.ismoving) && app.scalebar.ismoving
                    bar_len_x = app.scalebar.bar.xlen/app.scalebar.scale.xr;
                    bar_len_y = app.scalebar.bar.ylen/app.scalebar.scale.yr;
                    if ~isempty(app.scalebar.handle_x) && isvalid(app.scalebar.handle_x)
                        app.scalebar.handle_x.XData = [mouse_pos_cur(1)-bar_len_x/2, ...
                            mouse_pos_cur(1)+bar_len_x/2];
                        app.scalebar.handle_x.YData = [mouse_pos_cur(2), ...
                            mouse_pos_cur(2)];
                        app.scalebar.txtobj_x.Position = [mouse_pos_cur(1), ...
                            mouse_pos_cur(2)+app.scalebar.sf*app.scalebar.bar.txt_space, 0];
                    end
                    if ~isempty(app.scalebar.handle_y) && isvalid(app.scalebar.handle_y)
                        app.scalebar.handle_y.XData = [mouse_pos_cur(1)+bar_len_x/2, ...
                            mouse_pos_cur(1)+bar_len_x/2];
                        app.scalebar.handle_y.YData = [mouse_pos_cur(2),...
                            mouse_pos_cur(2)-bar_len_y];
                        app.scalebar.txtobj_y.Position = [mouse_pos_cur(1)+bar_len_x/2 ...
                            + app.scalebar.sf*app.scalebar.bar.txt_space, ...
                            mouse_pos_cur(2)-bar_len_y/2, 0];
                    end
                end

            end
        end

        % Menu selected function: Menu_LoadMem
        function Menu_LoadMemSelected(app, event)
            [opts, rt, movie, app.varname] = loadvars();
            
            % refresh filename
            app.filename = "__inner_" + app.varname;

            % load variables to app workspace
            status = MemLoader(app, movie, opts, rt);
            if status ~= 0
                return;
            end

            GenSysMsg(app, ["VARIABLE_LOADED",app.varname.join(", ")']);

            % update the system exterior
            UpdateSystemAfterLoading(app);
            GenSysMsg(app, ["SYS_INITIALIZE_SUCCESS",""]);

        end

        % Value changed function: Switch_Log
        function Switch_LogValueChanged(app, event)
            value = app.Switch_Log.Value;

            app.TextArea_Log.Enable = value;
        end

        % Menu selected function: Menu_RegEvaluate
        function Menu_RegEvaluateSelected(app, event)

            if isempty(app.EV_handle) || ~isvalid(app.EV_handle)
                app.EV_handle = Evaluation(app);
            end
        end

        % Menu selected function: Menu_SaveTimeAs
        function Menu_SaveTimeAsSelected(app, event)
            if ~isempty(app.mov_aligned.Time) || ~isempty(app.mov_raw.Time)
                if ~isempty(app.filename) && exist(app.filename,"file")
                    [~,fname,~] = fileparts(app.filename);
                elseif ~isempty(app.varname)
                    fname = app.varname(1);
                else
                    fname = "processing";
                end

                [file, path] = uiputfile(...
                    {'*.tim', [app.region_info.mapping.TIME_SERIES_FILE.char(), ' (*.tim)'];...
                    '*.txt', [app.region_info.mapping.TEXT_FILE.char(),' (*.txt)'];...
                    '*.csv', ['CSV (',app.region_info.mapping.COMMA_SEPARATED.char(),') (*.csv)'];...
                    '*.xlsx', ['Excel ',app.region_info.mapping.WORKSHEET.char(),'(*.xlsx)'];...
                    '*.xls', ['Excel 97-2003 ',app.region_info.mapping.WORKSHEET.char(),'(*.xls)']},...
                    app.region_info.mapping.EXPORT_TIME,...
                    app.sv_path + string(fname));

                if isequal(path, 0) || isequal(file, 0)
                    return;
                else
                    fname = fullfile(path,file);

                    [~,~,ext] = fileparts(fname);
                    if ~isempty(app.mov_aligned.Time)
                        time = app.mov_aligned.Time;
                    else
                        time = app.mov_raw.Time;
                    end
                    if ext == ".tim"
                        writematrix(time, fname, "FileType","text");
                    else
                        writematrix(time, fname);
                    end
                    GenSysMsg(app, ["TIME_EXPORT_SUCCESS",""]);
                end
            else
                uialert(app.Reg3DV200WukongUIFigure, ...
                    app.region_info.mapping.TIME_MISSING, ...
                    app.region_info.mapping.WARNING, "Icon","warning");
            end
        end

        % Menu selected function: Menu_SaveGeoTformAs
        function Menu_SaveGeoTformAsSelected(app, event)
            if ~isempty(app.mov_aligned.Transformation)
                if ~isempty(app.filename) && exist(app.filename,"file")
                    [~,fname,~] = fileparts(app.filename);
                elseif ~isempty(app.varname)
                    fname = app.varname(1);
                else
                    fname = "processing";
                end

                [file, path] = uiputfile({'*.mat', ...
                    ['MAT ',app.region_info.mapping.FILE.char(),' (*.mat)']},...
                    app.region_info.mapping.EXPORT_GEOMETRIC_TRANSFORM, ...
                    app.sv_path + fname);

                if isequal(path, 0) || isequal(file, 0)
                    return;
                else
                    fname = fullfile(path,file);

                    writematrix(app.mov_aligned.Transformation, fname);

                    GenSysMsg(app, ["GEOMETRIC_TRANSFORMATION_EXPORT_SUCCESS",""]);
                end
            end
        end

        % Menu selected function: Menu_ExportLog
        function Menu_ExportLogSelected(app, event)
            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            path = pathToMLAPP+"\log";
            if ~isfolder(path)
                mkdir(path);
            end
            
            ExportTextArea_Log(app, path);

            GenSysMsg(app, ["LOGFILE_EXPORT_TO", " "+path]);
        end

        % Menu selected function: Menu_ClearLog
        function Menu_ClearLogSelected(app, event)
            
            ClearTextArea_Log(app);

            % clear system message
            app.system_message = [];
        end

        % Menu selected function: Menu_GenerateMask
        function Menu_GenerateMaskSelected(app, event)
            if isempty(app.MG_handle) || ~isvalid(app.MG_handle)
                app.MG_handle = GenMask(app);
            end

        end

        % Menu selected function: Menu_LoadMask
        function Menu_LoadMaskSelected(app, event)
            % load the mask and apply on current movie

            if app.is_data_masked == true
                % the function is removing the mask
                % recovery the data
                app.mov_raw.Movie = app.mdrp.raw;
                app.mov_aligned.Movie = app.mdrp.aligned;

                GenSysMsg(app, ["MASK_REMOVED",""]);

                % change the record status and menu function
                app.Menu_LoadMask.Text = app.region_info.mapping.IMPORT_MASK;
                app.is_data_masked = false;
            else
                % the function is loading the mask
                % Get the file path for locating images
                pathToMLAPP = fileparts(mfilename('fullpath'));

                [file, path] = uigetfile({'*.mat', ...
                    ['MAT ',app.region_info.mapping.FILE.char(),' (*.mat)']}, ...
                    app.region_info.mapping.IMPORT_MASK,...
                    pathToMLAPP);
                HoldWindowOn(app);

                if path ~= 0
                    file = fullfile(path, file);
                    mask = load(file).mask_to_save;    % logical mask
                    if any(size(mask) ~= size(app.mov_raw.Movie, [1,2,4]))
                        uialert(app.Reg3DV200WukongUIFigure, ...
                            app.region_info.mapping.MASK_MISMATCH,...
                            app.region_info.mapping.ERROR, "Icon","error");
                        return;
                    else
                        % setup the recovery point
                        app.mdrp.raw = app.mov_raw.Movie;
                        app.mdrp.aligned = app.mov_aligned.Movie;

                        % mask the data
                        D = MaskMovie(app.mov_raw.Movie,...
                            mask);
                        app.mov_raw.Movie = D;
                        D = MaskMovie(app.mov_aligned.Movie,...
                            mask);
                        app.mov_aligned.Movie = D;

                        GenSysMsg(app, ["MASK_LOADED",""]);

                        % change the record status and menu function
                        app.Menu_LoadMask.Text = app.region_info.mapping.REMOVE;
                        app.is_data_masked = true;
                    end
                end
            end

            RefreshViewer(app);

        end

        % Value changed function: Button_Run
        function Button_RunValueChanged(app, event)
            value = app.Button_Run.Value;

            % new framework test
            if value == true
                fixdef = struct("Sampling", ["mean", "1"], ...
                                "Channel", "r");
                tmpl = regtmpl(app.mov_raw, fixdef);
                regopts = regopt("TCREG", "global");
                regopts.set("MaxStep", 0.05);
                regopts.set("MinStep", 1e-6);
                regopts.set("MaxIterN", 100);
                rcl = RegisterController(app, regopts, 1, app.memmap_flag);
                app.mov_aligned = app.mov_raw.copy();      % copy test
                rcl.run(app.mov_raw, app.mov_aligned, tmpl,1:100);
            else
                % rcl.stop();
            end


            if value == true
                app.reg_require = "on";
            else
                app.reg_require = "off";
            end

            % timer may be sleeping, kick it
            KickChecker(app);

        end

        % Menu selected function: Menu_TimeStamp
        function Menu_TimeStampSelected(app, event)
            % switch the checked status
            value = app.Menu_TimeStamp.Checked;

            if value == false
                % new text object on screen
                app.time_text.handle = text(app.UIAxes_Slice,1,10,0,"00:00:00.000",...
                    "FontSize",14,"FontName","Times New Roman","Color",[1,1,0],...
                    "HorizontalAlignment","left");
                time = seconds(app.mov_raw.Time);
                time.Format = 'hh:mm:ss.SSS';
                app.time_text.text = string(time);
                app.time_text.handle.String = app.time_text.text(app.frame_idx_cur);
                app.time_text.ismoving = false;
                app.time_text.handle.ButtonDownFcn = ...
                    createCallbackFcn(app,@TimeText_ButtonDownFcn,true);
                app.Menu_TimeStamp.Checked = "on";
            else
                delete(app.time_text.handle);
                app.time_text.handle = [];
                app.time_text.ismoving = [];
                app.Menu_TimeStamp.Checked = "off";
            end
        end

        % Menu selected function: Menu_MetaMarker
        function Menu_MetaMarkerSelected(app, event)
            % switch the checked status
            value = app.Menu_MetaMarker.Checked;

            if value == false
                % load the meta data file
                metadata = LoadMetaData(app.mov_raw.Time);
                if ~isempty(metadata)
                    % new text object on screen
                    app.meta_text.handle = text(app.UIAxes_Slice,app.mov_raw.MetaData.width-50,10,0,"water",...
                        "FontSize",14,"FontName","Times New Roman","Color",[1,1,0],...
                        "HorizontalAlignment","right");
                    app.meta_text.text = metadata;
                    app.meta_text.ismoving = false;
                    app.meta_text.handle.ButtonDownFcn = ...
                        createCallbackFcn(app,@MetaText_ButtonDownFcn,true);
                    app.Menu_MetaMarker.Checked = "on";
                end
            else
                delete(app.meta_text.handle);
                app.meta_text.handle = [];
                app.meta_text.text = [];
                app.meta_text.ismoving = [];
                app.Menu_MetaMarker.Checked = "off";
            end
        end

        % Menu selected function: Menu_SaveMovieAs
        function Menu_SaveMovieAsSelected(app, event)
            app.EM_handle = ExportMovie(app);
            waitfor(app.EM_handle);

            if ~isempty(app.mov_export_setting)
                if ~isempty(app.mov_export_setting.movie_name)
                    fname = app.mov_export_setting.movie_name;
                else
                    pathToMLAPP = fileparts(mfilename('fullpath'));
                    t = datetime('now','TimeZone','local','Format','yyMMdd');
                    fname = fullfile(pathToMLAPP, string(t)+".avi");
                end
                % lock the viewer controller and record status
                slider_slice_status = app.Slider_Slice.Enable;
                app.Slider_Slice.Enable = "off";
                app.Slider_Time.Enable = "off";
                key_mode_old =  app.key_mode;
                app.key_mode = "one-step";
                app.Menu_KeyMode.Enable = "off";
                frame_idx = app.frame_idx_cur;

                success_flag = true;

                % set the video writer
                v = VideoWriter(fname,...
                    app.mov_export_setting.movie_type);
                switch app.mov_export_setting.movie_type
                    case "Motion JPEG 2000"
                        v.CompressionRatio = app.mov_export_setting.compression_ratio;
                        v.LosslessCompression = app.mov_export_setting.is_nocompression;
                    otherwise
                        if app.mov_export_setting.movie_type == "Motion JPEG AVI"...
                                || app.mov_export_setting.movie_type == "MPEG-4"
                            v.Quality = app.mov_export_setting.quality;
                        elseif app.mov_export_setting.movie_type == "Uncompressed AVI"
                            %
                        end
                end
                v.FrameRate = app.mov_export_setting.fps;

                % open the video file
                open(v);

                bar = uiprogressdlg(app.Reg3DV200WukongUIFigure,...
                    "Cancelable","on","Icon","info","ShowPercentage","on",...
                    "Title",app.region_info.mapping.INFORMATION, ...
                    "Message",app.region_info.mapping.RENDERING,...
                    "Value",0);
                drawnow;

                % setup the capture screen
                ss = get(0).ScreenSize(3:4);
                ss_uifig = app.Reg3DV200WukongUIFigure.Position(3:4);
                ex_fig = figure;
                setFigure(ex_fig,cat(2,[0,0],ss_uifig./ss));
                set(ex_fig,"Visible","off");   % hide the window

                for k = 1:app.mov_raw.MetaData.frames
                    % check for cancel button press
                    if bar.CancelRequested
                        success_flag = false;
                        break;
                    end

                    % reset the frame
                    app.frame_idx_cur = k;
                    RefreshViewer(app);

                    % clear capture figure and copy objects
                    clf(ex_fig);
                    copyobj(app.UIAxes_Slice, ex_fig);
                    ax = get(ex_fig,"CurrentAxes");
                    if ~isempty(app.roi_handle) && isvalid(app.roi_handle)
                        [rect,w,h] = ROI2Rectangle(app.roi_handle);
                        f = getframe(ax,[rect(1,1),rect(2,2),w,h]);
                    else
                        f = getframe(ax);
                    end

                    % resize the rgb
                    if app.mov_export_setting.is_rescale
                        rgb = frame2im(f);
                        rgb = imresize(rgb,...
                            [app.mov_export_setting.height, app.mov_export_setting.width],...
                            app.mov_export_setting.interp_method);
                        f = im2frame(rgb);
                    end

                    % write the frame
                    writeVideo(v, f);

                    % update bar processing
                    bar.Value = k/app.mov_raw.MetaData.frames;
                    drawnow
                end

                % close processing bar
                close(bar);
                % close the video file
                close(v);
                % close the capture screen
                close(ex_fig);

                % unlock the viewer controller and recover key_mode
                app.Slider_Slice.Enable = slider_slice_status;
                app.frame_idx_cur = frame_idx;
                app.Slider_Time.Enable = "on";
                app.key_mode = key_mode_old;
                app.Menu_KeyMode.Enable = "on";

                RefreshViewer(app);

                if success_flag
                    GenSysMsg(app, ["MOVIE_EXPORTED_SUCCESS",""]);
                else
                    GenSysMsg(app, ["MOVIE_EXPORT_INTERRUPTED",""]);
                end
            end

        end

        % Menu selected function: Menu_RegRange
        function Menu_RegRangeSelected(app, event)
            prompt = {['\fontsize{12} ', app.region_info.mapping.TIME_RANGE.char()]};
            dlgtitle = app.region_info.mapping.UNREGISTRATERED_FRAMES.char();
            dims = [1 40];
            definput = {'1:end'};
            dlgopts.WindowStyle = 'normal';
            dlgopts.Interpreter = "tex";
            dlgout = inputdlg(prompt,dlgtitle,dims,definput,dlgopts);
            if isempty(dlgout)
                return;
            else
                dlgout=dlgout{1};
            end
            dlgout = string(dlgout).replace("end",num2str(app.mov_raw.MetaData.frames));
            app.reg_frames = str2num(dlgout); %#ok<ST2NM>
        end

        % Menu selected function: Menu_ScaleBar
        function Menu_ScaleBarSelected(app, event)
            app.scalebar.islocking = true;

            app.SB_handle = ScaleBar(app);
            uiwait(app.SB_handle.ScaleBarUIFigure);

            app.scalebar.islocking = false;

        end

        % Close request function: Reg3DV200WukongUIFigure
        function Reg3DV200WukongUIFigureCloseRequest(app, event)
            Register.EnableWarnings();

            % stop the polling timer
            RemoveTimer(app);

            % delete children handle
            delete(app.BC_handle);
            delete(app.EV_handle);
            delete(app.MG_handle);
            delete(app.RS_handle);
            delete(app.SB_handle);
            delete(app.BR_handle);

            % finish the final setting
            is_update = app.update_opts.is_update;  % local variable lives longer
            close(app.sftp_to_labnas);
            delete(app);

            % check the update option
            if is_update
                run(fullfile(userpath, "auto_update.m"));
                delete(fullfile(userpath, "auto_update.m"));
            end
        end

        % Menu selected function: Menu_RegOptions
        function Menu_RegOptionsSelected(app, event)
            % This function assigns different registration setting by
            % using channel(s) number
            if isempty(app.RS_handle) || ~isvalid(app.RS_handle)
                if numel(app.channel_order) == 1
                    % calling the one channel setting subapp
                    app.RS_handle = RegSetting_OC(app);
                elseif numel(app.channel_order) == 2
                    % calling the two channels setting subapp
                    app.RS_handle = RegSetting_TC(app);
                else
                    app.RS_handle = [];
                    uialert(app.Reg3DV200WukongUIFigure, ...
                       app.region_info.mapping.UNSUPPORTED_CHANNEL_NUMBER, ...
                        app.region_info.mapping.ERROR, "Icon","error");
                    return;
                end
            end
        end

        % Menu selected function: Menu_Update
        function Menu_UpdateSelected(app, event)
            
            RunUpdateChecking(app);
        end

        % Menu selected function: Menu_Chinese
        function Menu_ChineseSelected(app, event)
            app.Menu_Chinese.Checked = "on";
            app.Menu_English.Checked = "off";
            app.Menu_Russian.Checked = "off";
            app.Menu_French.Checked = "off";
            app.Menu_Spanish.Checked = "off";

            SetSystemLanguage(app,"zh_CN");
        end

        % Menu selected function: Menu_English
        function Menu_EnglishSelected(app, event)
            app.Menu_Chinese.Checked = "off";
            app.Menu_English.Checked = "on";
            app.Menu_Russian.Checked = "off";
            app.Menu_French.Checked = "off";
            app.Menu_Spanish.Checked = "off";

            SetSystemLanguage(app, "en_US");
        end

        % Menu selected function: Menu_Russian
        function Menu_RussianSelected(app, event)
            app.Menu_Chinese.Checked = "off";
            app.Menu_English.Checked = "off";
            app.Menu_Russian.Checked = "on";
            app.Menu_French.Checked = "off";
            app.Menu_Spanish.Checked = "off";

            SetSystemLanguage(app, "ru_RU");
        end

        % Menu selected function: Menu_French
        function Menu_FrenchSelected(app, event)
            app.Menu_Chinese.Checked = "off";
            app.Menu_English.Checked = "off";
            app.Menu_Russian.Checked = "off";
            app.Menu_French.Checked = "on";
            app.Menu_Spanish.Checked = "off";

            SetSystemLanguage(app, "fr_FR");
        end

        % Menu selected function: Menu_Spanish
        function Menu_SpanishSelected(app, event)
            app.Menu_Chinese.Checked = "off";
            app.Menu_English.Checked = "off";
            app.Menu_Russian.Checked = "off";
            app.Menu_French.Checked = "off";
            app.Menu_Spanish.Checked = "on";

            SetSystemLanguage(app, "es_ES");
        end

        % Menu selected function: Menu_RegHistory
        function Menu_RegHistorySelected(app, event)
            uialert(app.Reg3DV200WukongUIFigure, ...
                app.region_info.mapping.DEVELOPING,...
                app.region_info.mapping.INFORMATION,...
                "Icon","info");
        end

        % Menu selected function: Menu_MaxZProjection
        function Menu_MaxZProjectionSelected(app, event)
            switch app.Menu_MaxZProjection.Checked
                case "on"
                    app.Menu_MaxZProjection.Checked = "off";
                    app.zproj_mode = "none";
                    SetSlider_Slice(app,'on');
                case "off"
                    app.Menu_MaxZProjection.Checked = "on";
                    app.Menu_MinZProjection.Checked = "off";
                    app.Menu_MedianZProjection.Checked = "off";
                    app.Menu_MeanZProjection.Checked = "off";

                    app.zproj_mode = "max";
                    SetSlider_Slice(app,'freeze');

                    PanelWaiting(app);
                    SourceZProjection(app,[],"max");
                    PanelRecovered(app);
                otherwise
            end

            RefreshViewer(app);
        end

        % Menu selected function: Menu_MinZProjection
        function Menu_MinZProjectionSelected(app, event)
            switch app.Menu_MinZProjection.Checked
                case "on"
                    app.Menu_MinZProjection.Checked = "off";
                    app.zproj_mode = "none";
                    SetSlider_Slice(app,'on');
                case "off"
                    app.Menu_MaxZProjection.Checked = "off";
                    app.Menu_MinZProjection.Checked = "on";
                    app.Menu_MedianZProjection.Checked = "off";
                    app.Menu_MeanZProjection.Checked = "off";

                    app.zproj_mode = "min";
                    SetSlider_Slice(app,'freeze');

                    PanelWaiting(app);
                    SourceZProjection(app,[],"min");
                    PanelRecovered(app);
                otherwise
            end

            RefreshViewer(app);
        end

        % Menu selected function: Menu_MedianZProjection
        function Menu_MedianZProjectionSelected(app, event)
            switch app.Menu_MedianZProjection.Checked
                case "on"
                    app.Menu_MedianZProjection.Checked = "off";
                    app.zproj_mode = "none";
                    SetSlider_Slice(app,'on');
                case "off"
                    app.Menu_MaxZProjection.Checked = "off";
                    app.Menu_MinZProjection.Checked = "off";
                    app.Menu_MedianZProjection.Checked = "on";
                    app.Menu_MeanZProjection.Checked = "off";

                    app.zproj_mode = "median";
                    SetSlider_Slice(app,'freeze');

                    PanelWaiting(app);
                    SourceZProjection(app,[],"median");
                    PanelRecovered(app);
                otherwise
            end

            RefreshViewer(app);
        end

        % Menu selected function: Menu_MeanZProjection
        function Menu_MeanZProjectionSelected(app, event)
            switch app.Menu_MeanZProjection.Checked
                case "on"
                    app.Menu_MeanZProjection.Checked = "off";
                    app.zproj_mode = "none";
                    SetSlider_Slice(app,'on');
                case "off"
                    app.Menu_MaxZProjection.Checked = "off";
                    app.Menu_MinZProjection.Checked = "off";
                    app.Menu_MedianZProjection.Checked = "off";
                    app.Menu_MeanZProjection.Checked = "on";

                    app.zproj_mode = "mean";
                    SetSlider_Slice(app,'freeze');

                    PanelWaiting(app);
                    SourceZProjection(app,[],"mean");
                    PanelRecovered(app);
                otherwise
            end

            RefreshViewer(app);
        end

        % Menu selected function: Menu_Quit
        function Menu_QuitSelected(app, event)
            
            Reg3DV200WukongUIFigureCloseRequest(app, event);
        end

        % Menu selected function: Menu_ChannelAlignment
        function Menu_ChannelAlignmentSelected(app, event)
            if app.calign_flag == false
                app.calign_flag = true;
                app.Menu_ChannelAlignment.Checked = "on";
                % lock the player controller
                LockViewerController(app);
                % call subapp channel alignment
                if isempty(app.CA_handle) || ~isvalid(app.CA_handle)
                    app.CA_handle = ChannelAlignment(app);
                    waitfor(app.CA_handle);
                end
                UnlockViewerController(app);
                app.calign_flag = false;
                app.Menu_ChannelAlignment.Checked = "off";
                RefreshViewer(app);
            else
                delete(app.CA_handle);
                UnlockViewerController(app);
                app.calign_flag = false;
                app.Menu_ChannelAlignment.Checked = "off";
                RefreshViewer(app);
            end
        end

        % Menu selected function: Menu_RemoveBackgroundAuto
        function Menu_RemoveBackgroundAutoSelected(app, event)
            % remove the possible status
            app.Menu_RemoveBackgroundFix.Checked = "off";
            app.Menu_RemoveBackgroundAuto.Checked = ...
                ~app.Menu_RemoveBackgroundAuto.Checked;

            if app.bkgextract_flag == true
                RecoverCameraBKG(app);
            else
                if app.Menu_RemoveBackgroundAuto.Checked == "on"
                    RemoveCameraBKG(app, 3);
                end
            end

            % Update the display resource
            SourceZProjection(app, [], app.zproj_mode);
            RefreshViewer(app);
        end

        % Menu selected function: Menu_RemoveBackgroundFix
        function Menu_RemoveBackgroundFixSelected(app, event)
            % remove the possible status
            app.Menu_RemoveBackgroundAuto.Checked = "off";
            app.Menu_RemoveBackgroundFix.Checked = ...
                ~ app.Menu_RemoveBackgroundFix.Checked;

            if app.bkgextract_flag == true
                RecoverCameraBKG(app);
            else
                if app.Menu_RemoveBackgroundFix.Checked == "on"
                    status = RemoveCameraBKG(app);
                    if status ~= 0
                        % undo
                        app.Menu_RemoveBackgroundFix.Checked = "off";
                    end
                end
            end

            % Update the display resource
            SourceZProjection(app, [], app.zproj_mode);
            RefreshViewer(app);
        end

        % Menu selected function: Menu_SubmitBug
        function Menu_SubmitBugSelected(app, event)
            if isempty(app.sftp_to_labnas)
                app.sftp_to_labnas = getSFTP(app.BUG_REPORT_HOST);
            end

            if isempty(app.BR_handle) || ~isvalid(app.BR_handle)
                if ~isempty(app.sftp_to_labnas)
                    app.BR_handle = BugReporter(app, app.sftp_to_labnas);
                end
            end
        end

        % Window scroll wheel function: Reg3DV200WukongUIFigure
        function Reg3DV200WukongUIFigureWindowScrollWheel(app, event)
            vSC = event.VerticalScrollCount;
            
            % update the slice viewer
            if ~isempty(app.im_handle) && isvalid(app.im_handle)
                tp = app.UIAxes_Slice.tightPosition();
                % xr = diff(app.UIAxes_Slice.XLim)/tp(3)*app.Panel_Control.Position(3);
                yr = diff(app.UIAxes_Slice.YLim)/tp(4)*app.Panel_SliceViewer.Position(4);
                % xvalid = 0.5*diff(app.UIAxes_Slice.XLim) + 0.5*[-xr, xr];
                tiset = app.UIAxes_Slice.TightInset;
                yvalid = 0.5*diff(app.UIAxes_Slice.YLim) + ...
                    0.5*[-yr, yr] + 0.5*[tiset(2)+20, tiset(4)];
                if (app.UIAxes_Slice.CurrentPoint(2,2) > yvalid(1)) ...
                        && (app.UIAxes_Slice.CurrentPoint(2,2) < yvalid(2))
                    dz = 0;
                    if vSC < 0
                        while dz > vSC, NextSlicePtr(app); dz=dz-1; end
                    else
                        while dz < vSC, PrevSlicePtr(app); dz=dz+1; end
                    end
                    RefreshViewer(app);
                end
            end
            
        end

        % Menu selected function: Menu_RunModalDistributed
        function Menu_RunModalDistributedSelected(app, event)
            app.Menu_RunModalDistributed.Checked = "on";
            app.Menu_RunModalExclusive.Checked = "off";
            app.memmap_flag = true;

            ChangeRunModal(app);
        end

        % Menu selected function: Menu_RunModalExclusive
        function Menu_RunModalExclusiveSelected(app, event)
            if app.is_server
                slt = uiconfirm(app.Reg3DV200WukongUIFigure, ...
                    app.region_info.mapping.EXCLUSIVE_WARNING, ...
                    app.region_info.mapping.WARNING, ...
                    "Icon","warning");
                if slt == "OK"
                    app.Menu_RunModalDistributed.Checked = "off";
                    app.Menu_RunModalExclusive.Checked = "on";
                    app.memmap_flag = false;
                else
                    return;
                end
            else
                avail_mem = GetAvailableMemory();
                tot_mem = app.mov_raw.Bytes.map + app.mov_aligned.Bytes.map;
                if avail_mem > tot_mem * 2.5       % 2.5 as capacity coefficient
                    app.Menu_RunModalDistributed.Checked = "off";
                    app.Menu_RunModalExclusive.Checked = "on";
                    app.memmap_flag = false;
                else
                    uialert(app.Reg3DV200WukongUIFigure, ...
                        app.region_info.mapping.LOW_MEMORY_WARNING, ...
                        app.region_info.WARNING, ...
                        "Icon","warning");
                end
            end

            ChangeRunModal(app);
        end

        % Menu selected function: Menu_TaskViewer
        function Menu_TaskViewerSelected(app, event)
            
        end

        % Size changed function: Reg3DV200WukongUIFigure
        function Reg3DV200WukongUIFigureSizeChanged(app, event)
            if ~isempty(app.im_handle) && isvalid(app.im_handle)
                UpdateYScrollLim(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Reg3DV200WukongUIFigure = uifigure('Visible', 'off');
            app.Reg3DV200WukongUIFigure.Name = 'Reg3D V2.0.0 (Wukong)';
            app.Reg3DV200WukongUIFigure.Icon = fullfile(pathToMLAPP, 'sources', 'icon.png');
            app.Reg3DV200WukongUIFigure.CloseRequestFcn = createCallbackFcn(app, @Reg3DV200WukongUIFigureCloseRequest, true);
            app.Reg3DV200WukongUIFigure.SizeChangedFcn = createCallbackFcn(app, @Reg3DV200WukongUIFigureSizeChanged, true);
            app.Reg3DV200WukongUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @Reg3DV200WukongUIFigureWindowButtonMotion, true);
            app.Reg3DV200WukongUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @Reg3DV200WukongUIFigureWindowScrollWheel, true);
            app.Reg3DV200WukongUIFigure.KeyPressFcn = createCallbackFcn(app, @Reg3DV200WukongUIFigureKeyPress, true);
            app.Menu_File = uimenu(app.Reg3DV200WukongUIFigure);
            app.Menu_File.Tooltip = {'导入与导出'};
            app.Menu_File.Text = '文件';
            app.Menu_LoadFile = uimenu(app.Menu_File);
            app.Menu_LoadFile.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadFileSelected, true);
            app.Menu_LoadFile.Text = '加载文件';
            app.Menu_LoadMem = uimenu(app.Menu_File);
            app.Menu_LoadMem.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadMemSelected, true);
            app.Menu_LoadMem.Text = '加载工作区变量';
            app.Menu_SaveImageAs = uimenu(app.Menu_File);
            app.Menu_SaveImageAs.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveImageAsSelected, true);
            app.Menu_SaveImageAs.Separator = 'on';
            app.Menu_SaveImageAs.Text = '导出图像';
            app.Menu_SaveGeoTformAs = uimenu(app.Menu_File);
            app.Menu_SaveGeoTformAs.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveGeoTformAsSelected, true);
            app.Menu_SaveGeoTformAs.Text = '导出几何变换';
            app.Menu_SaveTimeAs = uimenu(app.Menu_File);
            app.Menu_SaveTimeAs.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveTimeAsSelected, true);
            app.Menu_SaveTimeAs.Text = '导出时间';
            app.Menu_SaveMovieAs = uimenu(app.Menu_File);
            app.Menu_SaveMovieAs.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveMovieAsSelected, true);
            app.Menu_SaveMovieAs.Text = '导出影片';
            app.Menu_Quit = uimenu(app.Menu_File);
            app.Menu_Quit.MenuSelectedFcn = createCallbackFcn(app, @Menu_QuitSelected, true);
            app.Menu_Quit.Separator = 'on';
            app.Menu_Quit.Text = '退出';
            app.Menu_Image = uimenu(app.Reg3DV200WukongUIFigure);
            app.Menu_Image.Tooltip = {'图像与标注'};
            app.Menu_Image.Text = '图像';
            app.Menu_LightContrast = uimenu(app.Menu_Image);
            app.Menu_LightContrast.MenuSelectedFcn = createCallbackFcn(app, @Menu_LightContrastSelected, true);
            app.Menu_LightContrast.Text = '亮度/对比度';
            app.Menu_ZProjection = uimenu(app.Menu_Image);
            app.Menu_ZProjection.Text = 'Z投影';
            app.Menu_MaxZProjection = uimenu(app.Menu_ZProjection);
            app.Menu_MaxZProjection.MenuSelectedFcn = createCallbackFcn(app, @Menu_MaxZProjectionSelected, true);
            app.Menu_MaxZProjection.Text = '最大值';
            app.Menu_MinZProjection = uimenu(app.Menu_ZProjection);
            app.Menu_MinZProjection.MenuSelectedFcn = createCallbackFcn(app, @Menu_MinZProjectionSelected, true);
            app.Menu_MinZProjection.Text = '最小值';
            app.Menu_MedianZProjection = uimenu(app.Menu_ZProjection);
            app.Menu_MedianZProjection.MenuSelectedFcn = createCallbackFcn(app, @Menu_MedianZProjectionSelected, true);
            app.Menu_MedianZProjection.Text = '中位值';
            app.Menu_MeanZProjection = uimenu(app.Menu_ZProjection);
            app.Menu_MeanZProjection.MenuSelectedFcn = createCallbackFcn(app, @Menu_MeanZProjectionSelected, true);
            app.Menu_MeanZProjection.Text = '平均值';
            app.Menu_ChannelAlignment = uimenu(app.Menu_Image);
            app.Menu_ChannelAlignment.MenuSelectedFcn = createCallbackFcn(app, @Menu_ChannelAlignmentSelected, true);
            app.Menu_ChannelAlignment.Text = '通道对齐';
            app.Menu_RemoveBackGround = uimenu(app.Menu_Image);
            app.Menu_RemoveBackGround.Text = '移除背景';
            app.Menu_RemoveBackgroundAuto = uimenu(app.Menu_RemoveBackGround);
            app.Menu_RemoveBackgroundAuto.MenuSelectedFcn = createCallbackFcn(app, @Menu_RemoveBackgroundAutoSelected, true);
            app.Menu_RemoveBackgroundAuto.Text = '自动';
            app.Menu_RemoveBackgroundFix = uimenu(app.Menu_RemoveBackGround);
            app.Menu_RemoveBackgroundFix.MenuSelectedFcn = createCallbackFcn(app, @Menu_RemoveBackgroundFixSelected, true);
            app.Menu_RemoveBackgroundFix.Text = '固定';
            app.Menu_MaskManager = uimenu(app.Menu_Image);
            app.Menu_MaskManager.Separator = 'on';
            app.Menu_MaskManager.Text = '蒙版';
            app.Menu_GenerateMask = uimenu(app.Menu_MaskManager);
            app.Menu_GenerateMask.MenuSelectedFcn = createCallbackFcn(app, @Menu_GenerateMaskSelected, true);
            app.Menu_GenerateMask.Text = '生成';
            app.Menu_LoadMask = uimenu(app.Menu_MaskManager);
            app.Menu_LoadMask.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadMaskSelected, true);
            app.Menu_LoadMask.Text = '导入';
            app.Menu_Comments = uimenu(app.Menu_Image);
            app.Menu_Comments.Text = '添加注释';
            app.Menu_TimeStamp = uimenu(app.Menu_Comments);
            app.Menu_TimeStamp.MenuSelectedFcn = createCallbackFcn(app, @Menu_TimeStampSelected, true);
            app.Menu_TimeStamp.Text = '时间戳';
            app.Menu_ScaleBar = uimenu(app.Menu_Comments);
            app.Menu_ScaleBar.MenuSelectedFcn = createCallbackFcn(app, @Menu_ScaleBarSelected, true);
            app.Menu_ScaleBar.Text = '比例 / 尺';
            app.Menu_MetaMarker = uimenu(app.Menu_Comments);
            app.Menu_MetaMarker.MenuSelectedFcn = createCallbackFcn(app, @Menu_MetaMarkerSelected, true);
            app.Menu_MetaMarker.Text = '元数据';
            app.Menu_Crop3D = uimenu(app.Menu_Image);
            app.Menu_Crop3D.Separator = 'on';
            app.Menu_Crop3D.Text = '体积裁剪';
            app.Menu_CropXY = uimenu(app.Menu_Crop3D);
            app.Menu_CropXY.MenuSelectedFcn = createCallbackFcn(app, @Menu_CropXYSelected, true);
            app.Menu_CropXY.Text = 'XY裁剪';
            app.Menu_CropZ = uimenu(app.Menu_Crop3D);
            app.Menu_CropZ.MenuSelectedFcn = createCallbackFcn(app, @Menu_CropZSelected, true);
            app.Menu_CropZ.Text = 'Z裁剪';
            app.Menu_CropT = uimenu(app.Menu_Image);
            app.Menu_CropT.MenuSelectedFcn = createCallbackFcn(app, @Menu_CropTSelected, true);
            app.Menu_CropT.Text = '帧裁剪';
            app.Menu_Options = uimenu(app.Reg3DV200WukongUIFigure);
            app.Menu_Options.Tooltip = {'设置与首选项'};
            app.Menu_Options.Text = '选项';
            app.Menu_SaveSetting = uimenu(app.Menu_Options);
            app.Menu_SaveSetting.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveSettingSelected, true);
            app.Menu_SaveSetting.Separator = 'on';
            app.Menu_SaveSetting.Text = '导出预设';
            app.Menu_LoadSetting = uimenu(app.Menu_Options);
            app.Menu_LoadSetting.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadSettingSelected, true);
            app.Menu_LoadSetting.Text = '加载预设';
            app.Menu_Animation = uimenu(app.Menu_Options);
            app.Menu_Animation.Separator = 'on';
            app.Menu_Animation.Text = '动画';
            app.Menu_1F = uimenu(app.Menu_Animation);
            app.Menu_1F.MenuSelectedFcn = createCallbackFcn(app, @Menu_1FSelected, true);
            app.Menu_1F.Text = '1X';
            app.Menu_5F = uimenu(app.Menu_Animation);
            app.Menu_5F.MenuSelectedFcn = createCallbackFcn(app, @Menu_5FSelected, true);
            app.Menu_5F.Text = '5X';
            app.Menu_25F = uimenu(app.Menu_Animation);
            app.Menu_25F.MenuSelectedFcn = createCallbackFcn(app, @Menu_25FSelected, true);
            app.Menu_25F.Text = '25X';
            app.Menu_KeyMode = uimenu(app.Menu_Options);
            app.Menu_KeyMode.Text = '播放模式';
            app.Menu_Direct = uimenu(app.Menu_KeyMode);
            app.Menu_Direct.MenuSelectedFcn = createCallbackFcn(app, @Menu_DirectSelected, true);
            app.Menu_Direct.Text = '单向';
            app.Menu_Loop = uimenu(app.Menu_KeyMode);
            app.Menu_Loop.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoopSelected, true);
            app.Menu_Loop.Text = '循环';
            app.Menu_Register = uimenu(app.Menu_Options);
            app.Menu_Register.Separator = 'on';
            app.Menu_Register.Text = '配准器';
            app.Menu_RegOptions = uimenu(app.Menu_Register);
            app.Menu_RegOptions.MenuSelectedFcn = createCallbackFcn(app, @Menu_RegOptionsSelected, true);
            app.Menu_RegOptions.Text = '配准设置';
            app.Menu_RegRange = uimenu(app.Menu_Register);
            app.Menu_RegRange.MenuSelectedFcn = createCallbackFcn(app, @Menu_RegRangeSelected, true);
            app.Menu_RegRange.Text = '配准范围';
            app.Menu_RegEvaluate = uimenu(app.Menu_Register);
            app.Menu_RegEvaluate.MenuSelectedFcn = createCallbackFcn(app, @Menu_RegEvaluateSelected, true);
            app.Menu_RegEvaluate.Text = '配准评估';
            app.Menu_RegHistory = uimenu(app.Menu_Options);
            app.Menu_RegHistory.MenuSelectedFcn = createCallbackFcn(app, @Menu_RegHistorySelected, true);
            app.Menu_RegHistory.Text = '配准历史';
            app.Menu_RunModal = uimenu(app.Menu_Options);
            app.Menu_RunModal.Separator = 'on';
            app.Menu_RunModal.Text = '运行模态';
            app.Menu_RunModalDistributed = uimenu(app.Menu_RunModal);
            app.Menu_RunModalDistributed.MenuSelectedFcn = createCallbackFcn(app, @Menu_RunModalDistributedSelected, true);
            app.Menu_RunModalDistributed.Checked = 'on';
            app.Menu_RunModalDistributed.Text = '分布式';
            app.Menu_RunModalExclusive = uimenu(app.Menu_RunModal);
            app.Menu_RunModalExclusive.MenuSelectedFcn = createCallbackFcn(app, @Menu_RunModalExclusiveSelected, true);
            app.Menu_RunModalExclusive.Text = '独占';
            app.Menu_TaskViewer = uimenu(app.Menu_Options);
            app.Menu_TaskViewer.MenuSelectedFcn = createCallbackFcn(app, @Menu_TaskViewerSelected, true);
            app.Menu_TaskViewer.Text = '任务浏览器';
            app.Menu_Help = uimenu(app.Reg3DV200WukongUIFigure);
            app.Menu_Help.Tooltip = {'帮助与更新'};
            app.Menu_Help.Text = '帮助';
            app.Menu_About = uimenu(app.Menu_Help);
            app.Menu_About.MenuSelectedFcn = createCallbackFcn(app, @Menu_AboutSelected, true);
            app.Menu_About.Text = '关于';
            app.Menu_Documents = uimenu(app.Menu_Help);
            app.Menu_Documents.MenuSelectedFcn = createCallbackFcn(app, @Menu_DocumentsSelected, true);
            app.Menu_Documents.Text = '文档';
            app.Menu_Language = uimenu(app.Menu_Help);
            app.Menu_Language.Separator = 'on';
            app.Menu_Language.Text = '语言';
            app.Menu_Chinese = uimenu(app.Menu_Language);
            app.Menu_Chinese.MenuSelectedFcn = createCallbackFcn(app, @Menu_ChineseSelected, true);
            app.Menu_Chinese.Text = '中文';
            app.Menu_English = uimenu(app.Menu_Language);
            app.Menu_English.MenuSelectedFcn = createCallbackFcn(app, @Menu_EnglishSelected, true);
            app.Menu_English.Text = 'English';
            app.Menu_Russian = uimenu(app.Menu_Language);
            app.Menu_Russian.MenuSelectedFcn = createCallbackFcn(app, @Menu_RussianSelected, true);
            app.Menu_Russian.Text = 'Русский';
            app.Menu_French = uimenu(app.Menu_Language);
            app.Menu_French.MenuSelectedFcn = createCallbackFcn(app, @Menu_FrenchSelected, true);
            app.Menu_French.Text = 'Français';
            app.Menu_Spanish = uimenu(app.Menu_Language);
            app.Menu_Spanish.MenuSelectedFcn = createCallbackFcn(app, @Menu_SpanishSelected, true);
            app.Menu_Spanish.Text = 'Español';
            app.Menu_Update = uimenu(app.Menu_Help);
            app.Menu_Update.MenuSelectedFcn = createCallbackFcn(app, @Menu_UpdateSelected, true);
            app.Menu_Update.Separator = 'on';
            app.Menu_Update.Text = '检查更新';
            app.Menu_SubmitBug = uimenu(app.Menu_Help);
            app.Menu_SubmitBug.MenuSelectedFcn = createCallbackFcn(app, @Menu_SubmitBugSelected, true);
            app.Menu_SubmitBug.Text = '协助改进';
            app.Panel_SliceViewer = uipanel(app.Reg3DV200WukongUIFigure);
            app.Panel_SliceViewer.Title = '片层浏览器';
            app.GridLayout_Viewer = uigridlayout(app.Panel_SliceViewer);
            app.UIAxes_Slice = uiaxes(app.GridLayout_Viewer);
            title(app.UIAxes_Slice, 'Title')
            xlabel(app.UIAxes_Slice, 'X')
            ylabel(app.UIAxes_Slice, 'Y')
            zlabel(app.UIAxes_Slice, 'Z')
            app.UIAxes_Slice.Visible = 'off';
            app.Label = uilabel(app.GridLayout_Viewer);
            app.Label.Text = '时间(帧)';
            app.Slider_Time = uislider(app.GridLayout_Viewer);
            app.Slider_Time.MajorTicks = [];
            app.Slider_Time.ValueChangedFcn = createCallbackFcn(app, @Slider_TimeValueChanged, true);
            app.Slider_Time.MinorTicks = [];
            app.Slider_Time.Tooltip = {'进度条'};
            app.Label_2 = uilabel(app.GridLayout_Viewer);
            app.Label_2.Text = '片层';
            app.Slider_Slice = uislider(app.GridLayout_Viewer);
            app.Slider_Slice.Limits = [0 35];
            app.Slider_Slice.MajorTicks = [];
            app.Slider_Slice.ValueChangedFcn = createCallbackFcn(app, @Slider_SliceValueChanged, true);
            app.Slider_Slice.MinorTicks = [];
            app.Slider_Slice.Tooltip = {'片层微调'};
            app.GridLayout = uigridlayout(app.GridLayout_Viewer);
            app.Spinner_Time = uispinner(app.GridLayout);
            app.Spinner_Time.Limits = [1 Inf];
            app.Spinner_Time.ValueDisplayFormat = '%.0f';
            app.Spinner_Time.ValueChangedFcn = createCallbackFcn(app, @Spinner_TimeValueChanged, true);
            app.Spinner_Time.Tooltip = {'时间微调'};
            app.Spinner_Time.Value = 1;
            app.Spinner_Slice = uispinner(app.GridLayout_Viewer);
            app.Spinner_Slice.Limits = [1 Inf];
            app.Spinner_Slice.ValueDisplayFormat = '%.0f';
            app.Spinner_Slice.ValueChangedFcn = createCallbackFcn(app, @Spinner_SliceValueChanged, true);
            app.Spinner_Slice.Tooltip = {'片层微调'};
            app.Spinner_Slice.Value = 1;
            app.Panel_Control = uipanel(app.Reg3DV200WukongUIFigure);
            app.Panel_Control.Tooltip = {'控制配准程序执行'};
            app.Panel_Control.Title = '控制面板';
            app.GridLayout2 = uigridlayout(app.Panel_Control);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Switch_Log = uiswitch(app.GridLayout3, 'rocker');
            app.Switch_Log.Items = {'关', '开'};
            app.Switch_Log.ItemsData = {'off', 'on'};
            app.Switch_Log.ValueChangedFcn = createCallbackFcn(app, @Switch_LogValueChanged, true);
            app.Switch_Log.Tooltip = {'日志开关，关闭时停止输出'};
            app.Switch_Log.Value = 'on';
            app.TextArea_Log = uitextarea(app.GridLayout3);
            app.TextArea_Log.Editable = 'off';
            app.TextArea_Log.Placeholder = '欢迎使用Reg3D App';
            app.Label_20 = uilabel(app.GridLayout3);
            app.Label_20.Text = '日志';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.Label_19 = uilabel(app.GridLayout4);
            app.Label_19.Text = '浏览源';
            app.DropDown_Source = uidropdown(app.GridLayout4);
            app.DropDown_Source.Items = {};
            app.DropDown_Source.ItemsData = {'RAW', 'ALIGNED'};
            app.DropDown_Source.ValueChangedFcn = createCallbackFcn(app, @DropDown_SourceValueChanged, true);
            app.DropDown_Source.Tooltip = {'选择浏览器数据源'};
            app.DropDown_Source.Value = {};
            app.Button_Run = uibutton(app.GridLayout4, 'state');
            app.Button_Run.ValueChangedFcn = createCallbackFcn(app, @Button_RunValueChanged, true);
            app.Button_Run.Tooltip = {'运行配准程序'};
            app.Button_Run.Icon = fullfile(pathToMLAPP, 'sources', 'run.png');
            app.Button_Run.Text = '启动';
            app.ContextMenu_Player = uicontextmenu(app.Reg3DV200WukongUIFigure);
            app.Menu_Horizontal = uimenu(app.ContextMenu_Player);
            app.Menu_Horizontal.MenuSelectedFcn = createCallbackFcn(app, @Menu_HorizontalSelected, true);
            app.Menu_Horizontal.Enable = 'off';
            app.Menu_Horizontal.Text = '水平翻转';
            app.Menu_Vertical = uimenu(app.ContextMenu_Player);
            app.Menu_Vertical.MenuSelectedFcn = createCallbackFcn(app, @Menu_VerticalSelected, true);
            app.Menu_Vertical.Enable = 'off';
            app.Menu_Vertical.Text = '垂直翻转';
            app.Menu_ROI = uimenu(app.ContextMenu_Player);
            app.Menu_ROI.Enable = 'off';
            app.Menu_ROI.Text = '设置ROI';
            app.Menu_RectangleROI = uimenu(app.Menu_ROI);
            app.Menu_RectangleROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_RectangleROISelected, true);
            app.Menu_RectangleROI.Text = '矩形';
            app.Menu_CircleROI = uimenu(app.Menu_ROI);
            app.Menu_CircleROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_CircleROISelected, true);
            app.Menu_CircleROI.Text = '圆形';
            app.Menu_PolygonROI = uimenu(app.Menu_ROI);
            app.Menu_PolygonROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_PolygonROISelected, true);
            app.Menu_PolygonROI.Text = '多边形';
            app.Menu_FreehandROI = uimenu(app.Menu_ROI);
            app.Menu_FreehandROI.MenuSelectedFcn = createCallbackFcn(app, @Menu_FreehandROISelected, true);
            app.Menu_FreehandROI.Text = '自由选择';
            app.Menu_Channel = uimenu(app.ContextMenu_Player);
            app.Menu_Channel.Enable = 'off';
            app.Menu_Channel.Text = '颜色通道';
            % Assign app.ContextMenu_Player
            app.GridLayout_Viewer.ContextMenu = app.ContextMenu_Player;
            app.ContextMenu_Controller = uicontextmenu(app.Reg3DV200WukongUIFigure);
            app.Menu_ExportLog = uimenu(app.ContextMenu_Controller);
            app.Menu_ExportLog.MenuSelectedFcn = createCallbackFcn(app, @Menu_ExportLogSelected, true);
            app.Menu_ExportLog.Text = '导出';
            app.Menu_ClearLog = uimenu(app.ContextMenu_Controller);
            app.Menu_ClearLog.MenuSelectedFcn = createCallbackFcn(app, @Menu_ClearLogSelected, true);
            app.Menu_ClearLog.Text = '清空';
            % Assign app.ContextMenu_Controller
            app.TextArea_Log.ContextMenu = app.ContextMenu_Controller;
            % Show the figure after all components are created
            app.Reg3DV200WukongUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Register

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Reg3DV200WukongUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.Reg3DV200WukongUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Reg3DV200WukongUIFigure)
        end
    end
end